classdef bird_identify < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        play_voice         matlab.ui.control.Button
        identify_result    matlab.ui.control.TextArea
        Label_3            matlab.ui.control.Label
        identifyy          matlab.ui.control.Button
        Image_2            matlab.ui.control.Image
        music_information  matlab.ui.control.TextArea
        Label_2            matlab.ui.control.Label
        Button             matlab.ui.control.Button
        Label              matlab.ui.control.Label
        Image              matlab.ui.control.Image
        before_Spectrum    matlab.ui.control.UIAxes
        before_wave        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        draw_t;draw_w;
        FS_music;org_data;
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file,filepath]=uigetfile({'*.wav';'*.mp3'});
            filename=fullfile(filepath,file);
            [org_music,fs_music]=audioread(filename);
            len=length(org_music);t=0:1/fs_music:(len-1)/fs_music;app.draw_t=t;
            fp=0:fs_music/len:fs_music*(len-1)/len;wpic=2*fp/fs_music;app.draw_w=wpic;%归一化
            Music_information=strcat("The audio signal sampling points are ",num2str(len),".The sampling frequency is ",num2str(fs_music),"Hz.");
            app.music_information.Value=Music_information;
            app.FS_music=fs_music;app.org_data=org_music(:,1);
            %绘制导入音频波形及频谱
            plot(app.before_wave,t,org_music(:,1));app.before_wave.XTick=0:2:10;
            org_fft=fft(org_music(:,1));
            plot(app.before_Spectrum,wpic,abs(org_fft));
            app.Image_2.ImageSource='';
            app.identify_result.Value='';
        end

        % Button pushed function: identifyy
        function identifyyButtonPushed(app, event)
            [bird1,fs_bird1]=audioread('sound/云雀.wav');
            [bird2,fs_bird2]=audioread('sound/斑鸠.wav');
            [bird3,fs_bird3]=audioread('sound/喜鹊.wav');
            [bird4,fs_bird4]=audioread('sound/大雁.wav');
            [bird5,fs_bird5]=audioread('sound/布谷鸟.wav');
            [bird6,fs_bird6]=audioread('sound/白头翁.wav');
            [bird7,fs_bird7]=audioread('sound/公鸡.wav');
            [bird8,fs_bird8]=audioread('sound/海鸥.wav');
            [bird9,fs_bird9]=audioread('sound/黄鹂.wav');
            [bird10,fs_bird10]=audioread('sound/燕子.wav');
            cbird1=bird1(1:320208);cbird1_fft=fft(cbird1);len=length(cbird1);
            cbird2=bird2(1:320208);cbird2_fft=fft(cbird2);
            cbird3=bird3(1:320208);cbird3_fft=fft(cbird3);
            cbird4=bird4(1:320208);cbird4_fft=fft(cbird4);
            cbird5=bird5(1:320208);cbird5_fft=fft(cbird5);
            cbird6=bird6(1:320208);cbird6_fft=fft(cbird6);
            cbird7=bird7(1:320208);cbird7_fft=fft(cbird7);
            cbird8=bird8(1:320208);cbird8_fft=fft(cbird8);
            cbird9=bird9(1:320208);cbird9_fft=fft(cbird9);
            cbird10=bird10(1:320208);cbird10_fft=fft(cbird10);
            test_voice_fft=fft(app.org_data(1:len));
            test_voice=test_voice_fft(1:len/2);
            r1=xcorr(test_voice,cbird1_fft(1:len/2));M1=max(abs(r1));
            r2=xcorr(test_voice,cbird2_fft(1:len/2));M2=max(abs(r2));
            r3=xcorr(test_voice,cbird3_fft(1:len/2));M3=max(abs(r3));
            r4=xcorr(test_voice,cbird4_fft(1:len/2));M4=max(abs(r4));
            r5=xcorr(test_voice,cbird5_fft(1:len/2));M5=max(abs(r5));
            r6=xcorr(test_voice,cbird6_fft(1:len/2));M6=max(abs(r6));
            r7=xcorr(test_voice,cbird7_fft(1:len/2));M7=max(abs(r7));
            r8=xcorr(test_voice,cbird8_fft(1:len/2));M8=max(abs(r8));
            r9=xcorr(test_voice,cbird9_fft(1:len/2));M9=max(abs(r9));
            r10=xcorr(test_voice,cbird10_fft(1:len/2));M10=max(abs(r10));
            Mpp=[M1,M2,M3,M4,M5,M6,M7,M8,M9,M10];[~,Thisone]=max(Mpp);
            assignin("base","Mpp1",Mpp);
            switch(Thisone)
                case 1
                    app.identify_result.Value="此声音为云雀叫声";
                    app.Image_2.ImageSource='./云雀.jpg';
                case 2
                    app.identify_result.Value='此声音为斑鸠叫声';
                    app.Image_2.ImageSource='./斑鸠.png';
                case 3
                    app.identify_result.Value='此声音为喜鹊叫声';
                    app.Image_2.ImageSource='./喜鹊.png';
                case 4
                    app.identify_result.Value='此声音为大雁叫声';
                    app.Image_2.ImageSource='./大雁.png';
                case 5
                    app.identify_result.Value='此声音为布谷鸟叫声';
                    app.Image_2.ImageSource='./布谷鸟.png';
                case 6
                    app.identify_result.Value="此声音为白头翁叫声";
                    app.Image_2.ImageSource='./白头翁.png';
                case 7
                    app.identify_result.Value='此声音为公鸡叫声';
                    app.Image_2.ImageSource='./公鸡.png';
                case 8
                    app.identify_result.Value='此声音为海鸥叫声';
                    app.Image_2.ImageSource='./海鸥.png';
                case 9
                    app.identify_result.Value='此声音为黄鹂叫声';
                    app.Image_2.ImageSource='./黄鹂.png';
                case 10
                    app.identify_result.Value='此声音为燕子叫声';
                    app.Image_2.ImageSource='./燕子.png';
            end
        end

        % Button pushed function: play_voice
        function play_voiceButtonPushed(app, event)
            org=audioplayer(app.org_data,app.FS_music);
            play(org);pause(10);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.before_wave = uiaxes(app.UIFigure);
            title(app.before_wave, '音频信号波形')
            xlabel(app.before_wave, 't/s')
            ylabel(app.before_wave, '幅值')
            zlabel(app.before_wave, 'Z')
            app.before_Spectrum = uiaxes(app.UIFigure);
            title(app.before_Spectrum, '音频信号频谱')
            xlabel(app.before_Spectrum, 'w/\pi')
            ylabel(app.before_Spectrum, '幅值')
            zlabel(app.before_Spectrum, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'owl.png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '鸟声识别系统';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入音频';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '音频信息';
            app.music_information = uitextarea(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.identifyy = uibutton(app.UIFigure, 'push');
            app.identifyy.ButtonPushedFcn = createCallbackFcn(app, @identifyyButtonPushed, true);
            app.identifyy.Text = '开始识别';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '识别结果';
            app.identify_result = uitextarea(app.UIFigure);
            app.play_voice = uibutton(app.UIFigure, 'push');
            app.play_voice.ButtonPushedFcn = createCallbackFcn(app, @play_voiceButtonPushed, true);
            app.play_voice.Text = '播放音频';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bird_identify

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end