classdef Sginal_processer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        Tab_2                   matlab.ui.container.Tab
        voice_amplitude         matlab.ui.control.Slider
        Label_10                matlab.ui.control.Label
        ifok                    matlab.ui.control.Button
        play_music              matlab.ui.control.Button
        change_voice            matlab.ui.control.Slider
        Label_9                 matlab.ui.control.Label
        music_information       matlab.ui.control.TextArea
        Label_6                 matlab.ui.control.Label
        Label_4                 matlab.ui.control.Label
        LOGO                    matlab.ui.control.Image
        readin_music            matlab.ui.control.Button
        Filter_design           matlab.ui.container.Panel
        get_filter              matlab.ui.control.Button
        FIRPanel                matlab.ui.container.Panel
        FIR_Fs                  matlab.ui.control.EditField
        FsHzEditFieldLabel      matlab.ui.control.Label
        FIR_Fp                  matlab.ui.control.EditField
        FpHzEditFieldLabel_2    matlab.ui.control.Label
        FIR_Rp                  matlab.ui.control.NumericEditField
        RpdBEditFieldLabel      matlab.ui.control.Label
        FIR_Rs                  matlab.ui.control.NumericEditField
        RsdBEditFieldLabel      matlab.ui.control.Label
        FIRLabel                matlab.ui.control.Label
        FIR_choose              matlab.ui.control.DropDown
        DropDown_7Label         matlab.ui.control.Label
        win_choose              matlab.ui.control.DropDown
        Label_12                matlab.ui.control.Label
        FIR_method              matlab.ui.control.DropDown
        DropDown_5Label         matlab.ui.control.Label
        IIRPanel                matlab.ui.container.Panel
        IIR_Fs                  matlab.ui.control.EditField
        FpHzEditField_2Label_2  matlab.ui.control.Label
        IIR_Fp                  matlab.ui.control.EditField
        FpHzEditFieldLabel      matlab.ui.control.Label
        IIR_Rp                  matlab.ui.control.NumericEditField
        RpdBEditField_2Label    matlab.ui.control.Label
        IIR_Rs                  matlab.ui.control.NumericEditField
        FsLabel                 matlab.ui.control.Label
        IIRLabel                matlab.ui.control.Label
        IIR_method              matlab.ui.control.DropDown
        Label_7                 matlab.ui.control.Label
        IIR_choose              matlab.ui.control.DropDown
        DropDown_3Label         matlab.ui.control.Label
        IIR_filterchoose        matlab.ui.control.DropDown
        Label_2                 matlab.ui.control.Label
        Switch                  matlab.ui.control.ToggleSwitch
        Filter_phase            matlab.ui.control.UIAxes
        Filter_amplitude        matlab.ui.control.UIAxes
        after_Spectrum          matlab.ui.control.UIAxes
        after_wave              matlab.ui.control.UIAxes
        before_Spectrum         matlab.ui.control.UIAxes
        before_wave             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        org_data;
        FS_music;
        filter_exist; % 检测是否设计了滤波器
        iir_method;
        iir_filterchoose;
        iir_choose;
        iir_Fp;iir_Fs;iir_Rp;iir_Rs;
        iir_ok;Bnum;A;
        fir_method;
        fir_winchoose;
        fir_choose;
        fir_Fp;fir_Fs;fir_Rp;fir_Rs;
        hnn;
        draw_t;draw_w;
        voicechange  % 检测是否进行变声设计
        Lisen_obj
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Mystartup(app)
            %视图初始化
            app.FIR_Rp.Enable="off";
            app.FIR_Rs.Enable="off";
            app.FIR_Fp.Enable="off";
            app.FIR_Fs.Enable="off";
            app.FIR_choose.Enable="off";
            app.win_choose.Enable="off";
            app.FIR_method.Enable="off";
            %变量初始化
            app.filter_exist=0;
            app.iir_method=1;
            app.iir_filterchoose=1;
            app.iir_choose=1;
            app.fir_method=1;
            app.fir_winchoose=1;
            app.fir_choose=1;
            app.FS_music=44100;
            % app.IIR_Fp.Value=3000;
            % app.IIR_Fs.Value=4000;
            % app.IIR_Rp.Value=1;
            % app.IIR_Rs=30;
            % app.iir_Fp=3000;
            % app.iir_Fs=4000;
            % app.iir_Rp=1;
            % app.iir_Rs=30;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'IIR')
                app.FIR_Rp.Enable="off";
                app.FIR_Rs.Enable="off";
                app.FIR_Fp.Enable="off";
                app.FIR_Fs.Enable="off";
                app.FIR_choose.Enable="off";
                app.win_choose.Enable="off";
                app.FIR_method.Enable="off";

                app.IIR_Rp.Enable="on";
                app.IIR_Rs.Enable="on";
                app.IIR_Fp.Enable="on";
                app.IIR_Fs.Enable="on";
                app.IIR_choose.Enable="on";
                app.IIR_filterchoose.Enable="on";
                app.IIR_method.Enable="on";
            else
                app.FIR_Rp.Enable="on";
                app.FIR_Rs.Enable="on";
                app.FIR_Fp.Enable="on";
                app.FIR_Fs.Enable="on";
                app.FIR_choose.Enable="on";
                app.win_choose.Enable="on";
                app.FIR_method.Enable="on";

                app.IIR_Rp.Enable="off";
                app.IIR_Rs.Enable="off";
                app.IIR_Fp.Enable="off";
                app.IIR_Fs.Enable="off";
                app.IIR_choose.Enable="off";
                app.IIR_filterchoose.Enable="off";
                app.IIR_method.Enable="off";
            end
        end

        % Button pushed function: readin_music
        function readin_musicButtonPushed(app, event)
            [file,filepath]=uigetfile;
            filename=fullfile(filepath,file);
            [org_music,fs_music]=audioread(filename);
            len=length(org_music);t=0:1/fs_music:(len-1)/fs_music;app.draw_t=t;
            fp=0:fs_music/len:fs_music*(len-1)/len;wpic=2*fp/fs_music;app.draw_w=wpic;%归一化
            Music_information=strcat("The audio signal sampling points are ",num2str(len),".The sampling frequency is ",num2str(fs_music),"Hz.");
            app.music_information.Value=Music_information;
            app.FS_music=fs_music;app.org_data=org_music(:,1);
            %绘制导入音频波形及频谱
            plot(app.before_wave,t,org_music(:,1));app.before_wave.XTick=0:5:20;
            org_fft=fft(org_music(:,1));
            plot(app.before_Spectrum,wpic,abs(org_fft));
        end

        % Button pushed function: get_filter
        function get_filterButtonPushed(app, event)
            %设计滤波器，显示滤波器幅频特性和相频特性。
            app.filter_exist = 1;
            % assignin("base","iir_Fp",app.iir_Fp);assignin("base","iir_Fs",app.iir_Fs);
            % assignin("base","iir_Rp",app.iir_Rp);assignin("base","iir_Rs",app.iir_Rs);
            if(strcmp(app.IIR_method.Enable,"on"))
                switch(app.iir_method)
                    case 1%脉冲响应不变法
                        switch(app.iir_filterchoose)
                            case 1
                                switch(app.iir_choose)
                                    case 1
                                        [BU_N1,BU_Wn1]=buttord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N1,BU_Wn1,'low');
                                    case 2
                                        [BU_N2,BU_Wn2]=buttord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N2,BU_Wn2,'high');
                                    case 3
                                        [BU_N3,BU_Wn3]=buttord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N3,BU_Wn3,'bandpass');
                                    case 4
                                        [BU_N4,BU_Wn4]=buttord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N4,BU_Wn4,'stop');
                                end
                            case 2
                                switch(app.iir_choose)
                                    case 1
                                        [CH1_N1,CH1_Wn1]=cheb1ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N1,app.iir_Rp,CH1_Wn1,'low');
                                    case 2
                                        [CH1_N2,CH1_Wn2]=cheb1ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N2,app.iir_Rp,CH1_Wn2,'high');
                                    case 3
                                        [CH1_N3,CH1_Wn3]=cheb1ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N3,app.iir_Rp,CH1_Wn3,'bandpass');
                                    case 4
                                        [CH1_N4,CH1_Wn4]=cheb1ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N4,app.iir_Rp,CH1_Wn4,'stop');
                                end
                            case 3
                                switch(app.iir_choose)
                                    case 1
                                        [CH2_N1,CH2_Wn1]=cheb2ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N1,app.iir_Rp,CH2_Wn1,'low');
                                    case 2
                                        [CH2_N2,CH2_Wn2]=cheb2ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N2,app.iir_Rp,CH2_Wn2,'high');
                                    case 3
                                        [CH2_N3,CH2_Wn3]=cheb2ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N3,app.iir_Rp,CH2_Wn3,'bandpass');
                                    case 4
                                        [CH2_N4,CH2_Wn4]=cheb2ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N4,app.iir_Rp,CH2_Wn4,'stop');
                                end
                            case 4
                                switch(app.iir_choose)
                                    case 1
                                        [EL_N1,EL_Wn1]=ellipord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N1,app.iir_Rp,app.iir_Rs,EL_Wn1,'low');
                                    case 2
                                        [EL_N2,EL_Wn2]=ellipord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N2,app.iir_Rp,app.iir_Rs,EL_Wn2,'high');
                                    case 3
                                        [EL_N3,EL_Wn3]=ellipord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N3,app.iir_Rp,app.iir_Rs,EL_Wn3,'bandpass');
                                    case 4
                                        [EL_N4,EL_Wn4]=ellipord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N4,app.iir_Rp,app.iir_Rs,EL_Wn4,'stop');
                                end
                        end
                    case 2%双线性变换法
                        wp=(2*pi.*app.iir_Fp)./app.FS_music;ws=(2*pi.*app.iir_Fs)./app.FS_music;
                        twp=2.*tan(wp./2).*app.FS_music;tws=2.*tan(ws./2).*app.FS_music;%预畸变
                        assignin("base","iir_twp",twp);assignin("base","iir_tws",tws);
                        switch(app.iir_filterchoose)
                            case 1
                                switch(app.iir_choose)
                                    case 1
                                        [BU_N1,BU_Wn1]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,'s');
                                        [BU_B1,BU_A1]=butter(BU_N1,BU_Wn1,'low','s');
                                        [b,a]=bilinear(BU_B1,BU_A1,app.FS_music);
                                    case 2
                                        [BU_N2,BU_Wn2]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,'s');
                                        [BU_B2,BU_A2]=butter(BU_N2,BU_Wn2,'high','s');
                                        [b,a]=bilinear(BU_B2,BU_A2,app.FS_music);
                                    case 3
                                        [BU_N3,BU_Wn3]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [BU_B3,BU_A3]=butter(BU_N3,BU_Wn3,'bandpass','s');
                                        [b,a]=bilinear(BU_B3,BU_A3,app.FS_music);
                                    case 4
                                        [BU_N4,BU_Wn4]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [BU_B4,BU_A4]=butter(BU_N4,BU_Wn4,'stop','s');
                                        [b,a]=bilinear(BU_B4,BU_A4,app.FS_music);
                                end
                            case 2
                                switch(app.iir_choose)
                                    case 1
                                        [CH1_N1,CH1_Wn1]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B1,CH1_A1]=cheby1(CH1_N1,app.iir_Rp,CH1_Wn1,'low','s');
                                        [b,a]=bilinear(CH1_B1,CH1_A1,app.FS_music);
                                    case 2
                                        [CH1_N2,CH1_Wn2]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B2,CH1_A2]=cheby1(CH1_N2,app.iir_Rp,CH1_Wn2,'high','s');
                                        [b,a]=bilinear(CH1_B2,CH1_A2,app.FS_music);
                                    case 3
                                        [CH1_N3,CH1_Wn3]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B3,CH1_A3]=cheby1(CH1_N3,app.iir_Rp,CH1_Wn3,'bandpass','s');
                                        [b,a]=bilinear(CH1_B3,CH1_A3,app.FS_music);
                                    case 4
                                        [CH1_N4,CH1_Wn4]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B4,CH1_A4]=cheby1(CH1_N4,app.iir_Rp,CH1_Wn4,'stop','s');
                                        [b,a]=bilinear(CH1_B4,CH1_A4,app.FS_music);
                                end
                            case 3
                                switch(app.iir_choose)
                                    case 1
                                        [CH2_N1,CH2_Wn1]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B1,CH2_A1]=cheby2(CH2_N1,app.iir_Rp,CH2_Wn1,'low','s');
                                        [b,a]=bilinear(CH2_B1,CH2_A1,app.FS_music);
                                    case 2
                                        [CH2_N2,CH2_Wn2]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B2,CH2_A2]=cheby2(CH2_N2,app.iir_Rp,CH2_Wn2,'high','s');
                                        [b,a]=bilinear(CH2_B2,CH2_A2,app.FS_music);
                                    case 3
                                        [CH2_N3,CH2_Wn3]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B3,CH2_A3]=cheby2(CH2_N3,app.iir_Rp,CH2_Wn3,'bandpass','s');
                                        [b,a]=bilinear(CH2_B3,CH2_A3,app.FS_music);
                                    case 4
                                        [CH2_N4,CH2_Wn4]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B4,CH2_A4]=cheby2(CH2_N4,app.iir_Rp,CH2_Wn4,'stop','s');
                                        [b,a]=bilinear(CH2_B4,CH2_A4,app.FS_music);
                                end
                            case 4
                                switch(app.iir_choose)
                                    case 1
                                        [EL_N1,EL_Wn1]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B1,EL_A1]=ellip(EL_N1,app.iir_Rp,app.iir_Rs,EL_Wn1,'low','s');
                                        [b,a]=bilinear(EL_B1,EL_A1,app.FS_music);
                                    case 2
                                        [EL_N2,EL_Wn2]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B2,EL_A2]=ellip(EL_N2,app.iir_Rp,app.iir_Rs,EL_Wn2,'high','s');
                                        [b,a]=bilinear(EL_B2,EL_A2,app.FS_music);
                                    case 3
                                        [EL_N3,EL_Wn3]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B3,EL_A3]=ellip(EL_N3,app.iir_Rp,app.iir_Rs,EL_Wn3,'bandpass','s');
                                        [b,a]=bilinear(EL_B3,EL_A3,app.FS_music);
                                    case 4
                                        [EL_N4,EL_Wn4]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B4,EL_A4]=ellip(EL_N4,app.iir_Rp,app.iir_Rs,EL_Wn4,'stop','s');
                                        [b,a]=bilinear(EL_B4,EL_A4,app.FS_music);
                                end
                        end
                end
                [Hw,w]=freqz(b,a,1024);
                fpic=w./max(w).*app.FS_music./2;
                plot(app.Filter_amplitude,fpic,20*log10(abs(Hw)));xlim(app.Filter_amplitude,[0 app.FS_music/2]);
                plot(app.Filter_phase,fpic,angle(Hw));app.Filter_phase.XTick=app.Filter_amplitude.XTick;
                xlim(app.Filter_phase,[0 app.FS_music/2]);
                app.iir_ok=1;app.Bnum=b;app.A=a;
            else
                % assignin("base","Nb",Nb);
                switch(app.fir_method)
                    case 1%窗函数设计法
                        wc=(app.fir_Fp+app.fir_Fs)/app.FS_music; %理想低通滤波器截止频率(关于 pi 归一化）
                        B=abs(2*pi*(app.fir_Fs-app.fir_Fp)/app.FS_music); %过渡带宽度指标
                        Nb=ceil(11*pi/B); %blackman 窗的长度 N
                        switch(app.fir_winchoose)
                            case 1%汉宁窗
                                switch(app.fir_choose)
                                    case 1
                                        %msgbox("1");
                                        hn=fir1(Nb-1,wc,"low",hann(Nb));
                                    case 2
                                        % y=hann(Nb);
                                        % assignin("base","hannNb",y);
                                        hn=fir1(Nb-1,wc,"high",hann(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",hann(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",hann(Nb));
                                end
                            case 2%汉明窗
                                switch(app.fir_choose)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",hamming(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",hamming(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",hamming(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",hamming(Nb));
                                end
                            case 3%布莱克曼窗
                                switch(app.fir_choose)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",blackman(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",blackman(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",blackman(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",blackman(Nb));
                                end
                            case 4%矩形窗
                                switch(app.fir_choose)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",rectwin(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",rectwin(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",rectwin(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",rectwin(Nb));
                                end
                            case 5%三角型窗
                                switch(app.fir_choose)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",triang(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",triang(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",triang(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",triang(Nb));
                                end
                            case 6%凯赛窗
                                switch(app.fir_choose)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",kaiser(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",kaiser(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",kaiser(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",kaiser(Nb));
                                end
                        end

                    case 2% 等波纹最佳逼近
                        switch(app.fir_choose)
                            case 1%低通
                                dev=[(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20)];
                                fb=[app.fir_Fp,app.fir_Fs];m=[1,0];% 确定 remezord 函数所需参数 f,m
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 2%高通
                                dev=[10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1)];
                                fb=[app.fir_Fs,app.fir_Fp];m=[0,1];% 确定 remezord 函数所需参数 f,m
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 3%带通
                                dev=[10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20)];
                                fb=[app.fir_Fs(1,1),app.fir_Fp(1,1),app.fir_Fp(1,2),app.fir_Fs(1,2)];m=[0,1,0];
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 4%带阻
                                dev=[(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1)];
                                fb=[app.fir_Fp(1,1),app.fir_Fs(1,1),app.fir_Fs(1,2),app.fir_Fp(1,2)];m=[1,0,1];
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                        end
                end
                Hw=fft(hn,1024); % 求设计的滤波器频率特性
                db_Hw=20*log(abs(Hw))/log(10);
                firw=0:1:1023;firw=firw./1024*app.FS_music;
                plot(app.Filter_amplitude,firw,db_Hw);axis(app.Filter_amplitude,[0,22050,-200,5]);
                plot(app.Filter_phase,firw,angle(Hw));app.Filter_phase.XTick=app.Filter_amplitude.XTick;
                xlim(app.Filter_phase,[0 app.FS_music/2]);
                app.hnn=hn;
            end
        end

        % Button pushed function: ifok
        function ifokButtonPushed(app, event)
            %显示波形和频谱图，判断输出哪一个音频数据。
            x=app.org_data;
            if(app.voicechange == 1)
                
                %app.Lisen_obj=audioplayer(y,app.FS_music);
                plot(app.after_wave,app.draw_t,y);app.after_wave.XTick=0:5:20;
                plot(app.after_Spectrum,app.draw_w,abs(Y));
            else    
                if(app.filter_exist == 1)
                    if(app.iir_ok)
                        y=filter(app.Bnum,app.A,x);Y=fft(y);
                        app.Lisen_obj=audioplayer(y,app.FS_music);
                        plot(app.after_wave,app.draw_t,y);app.after_wave.XTick=0:5:20;
                        plot(app.after_Spectrum,app.draw_w,abs(Y));
                    else
                        y=fftfilt(app.hnn,x,1000);Y=fft(y);
                        app.Lisen_obj=audioplayer(y,app.FS_music);
                        plot(app.after_wave,app.draw_t,y);app.after_wave.XTick=0:5:20;
                        plot(app.after_Spectrum,app.draw_w,abs(Y));
                    end
                end
            end
        end

        % Value changed function: change_voice
        function change_voiceValueChanged(app, event)
            value = app.change_voice.Value;
            app.voicechange = 1;

        end

        % Value changed function: IIR_method
        function IIR_methodValueChanged(app, event)
            value = app.IIR_method.Value;
            switch (value)
                case '1.脉冲响应不变法'
                    app.iir_method=1;
                    assignin('base','r_method',app.iir_method);
                case '2.双线性变换法'
                    app.iir_method=2;
                    assignin('base','r_method',app.iir_method);
            end
        end

        % Value changed function: IIR_Fp
        function IIR_FpValueChanged(app, event)
            value = app.IIR_Fp.Value;
            app.iir_Fp=str2num(value);
        end

        % Value changed function: IIR_filterchoose
        function IIR_filterchooseValueChanged(app, event)
            value = app.IIR_filterchoose.Value;
            switch (value)
                case '1.巴特沃斯滤波器'
                    app.iir_filterchoose=1;
                case '2.切比雪夫Ⅰ型'
                    app.iir_filterchoose=2;
                case '3.切比雪夫Ⅱ型'
                    app.iir_filterchoose=3;
                case '4.椭圆滤波器'
                    app.iir_filterchoose=4;
            end
        end

        % Value changed function: IIR_choose
        function IIR_chooseValueChanged(app, event)
            value = app.IIR_choose.Value;
            switch (value)
                case '1.低通'
                    app.iir_choose=1;
                case '2.高通'
                    app.iir_choose=2;
                case '3.带通'
                    app.iir_choose=3;
                    msgbox("带通滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                case '4.带阻'
                    app.iir_choose=4;
                    msgbox("带阻滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
            end
        end

        % Value changed function: IIR_Fs
        function IIR_FsValueChanged(app, event)
            value = app.IIR_Fs.Value;
            app.iir_Fs=str2num(value);
        end

        % Value changed function: IIR_Rp
        function IIR_RpValueChanged(app, event)
            value = app.IIR_Rp.Value;
            app.iir_Rp=value;
        end

        % Value changed function: IIR_Rs
        function IIR_RsValueChanged(app, event)
            value = app.IIR_Rs.Value;
            app.iir_Rs=value;
        end

        % Value changed function: FIR_method
        function FIR_methodValueChanged(app, event)
            value = app.FIR_method.Value;
            switch (value)
                case '1.窗函数设计法'
                    app.fir_method=1;
                case '2.等波纹最佳逼近法'
                    app.fir_method=2;
                    app.win_choose.Enable="off";
            end
        end

        % Value changed function: win_choose
        function win_chooseValueChanged(app, event)
            value = app.win_choose.Value;
            switch (value)
                case '1.汉宁窗'
                    app.fir_winchoose=1;
                case '2.汉明窗'
                    app.fir_winchoose=2;
                case '3.布莱克曼窗'
                    app.fir_winchoose=3;
                case '4.矩形窗'
                    app.fir_winchoose=4;
                case '5.三角型窗'
                    app.fir_winchoose=5;
                case '6.凯塞窗'
                    app.fir_winchoose=6;
            end
        end

        % Value changed function: FIR_choose
        function FIR_chooseValueChanged(app, event)
            value = app.FIR_choose.Value;
            switch (value)
                case '1.低通'
                    app.fir_choose=1;
                case '2.高通'
                    app.fir_choose=2;
                case '3.带通'
                    app.fir_choose=3;
                    switch(app.FIR_method.Value)
                        case '1.窗函数设计法'
                            msgbox("带通滤波器的通带上下限截止频率分别写在通带截止频率和阻带截止频率处。","提示",'help');
                        case '2.等波纹最佳逼近法'
                            msgbox("带通滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                    end
                case '4.带阻'
                    app.fir_choose=4;
                    switch(app.FIR_method.Value)
                        case '1.窗函数设计法'
                            msgbox("带阻滤波器的通带上下限截止频率分别写在通带截止频率和阻带截止频率处。","提示",'help');
                        case '2.等波纹最佳逼近法'
                            msgbox("带阻滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                    end
            end
        end

        % Value changed function: FIR_Fp
        function FIR_FpValueChanged(app, event)
            value = app.FIR_Fp.Value;
            app.fir_Fp=str2num(value);
        end

        % Value changed function: FIR_Fs
        function FIR_FsValueChanged(app, event)
            value = app.FIR_Fs.Value;
            app.fir_Fs=str2num(value);
        end

        % Value changed function: FIR_Rp
        function FIR_RpValueChanged(app, event)
            value = app.FIR_Rp.Value;
            app.fir_Rp=value;
        end

        % Value changed function: FIR_Rs
        function FIR_RsValueChanged(app, event)
            value = app.FIR_Rs.Value;
            app.fir_Rs=value;
        end

        % Button pushed function: play_music
        function play_musicButtonPushed(app, event)
            play(app.Lisen_obj);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '语音信号处理系统';
            app.before_wave = uiaxes(app.UIFigure);
            title(app.before_wave, '原信号波形')
            xlabel(app.before_wave, 't/s')
            ylabel(app.before_wave, '幅度')
            zlabel(app.before_wave, 'Z')
            app.before_wave.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.before_Spectrum = uiaxes(app.UIFigure);
            title(app.before_Spectrum, '原信号频谱')
            xlabel(app.before_Spectrum, 'ω/\pi')
            ylabel(app.before_Spectrum, '幅度')
            zlabel(app.before_Spectrum, 'Z')
            app.after_wave = uiaxes(app.UIFigure);
            title(app.after_wave, '处理后信号波形')
            xlabel(app.after_wave, 't/s')
            ylabel(app.after_wave, '幅度')
            zlabel(app.after_wave, 'Z')
            app.after_Spectrum = uiaxes(app.UIFigure);
            title(app.after_Spectrum, '处理后信号频谱')
            xlabel(app.after_Spectrum, 'ω/\pi')
            ylabel(app.after_Spectrum, '幅度')
            zlabel(app.after_Spectrum, 'Z')
            app.Filter_amplitude = uiaxes(app.UIFigure);
            title(app.Filter_amplitude, '滤波器幅频特性')
            xlabel(app.Filter_amplitude, 'f/Hz')
            ylabel(app.Filter_amplitude, '幅值(dB)')
            zlabel(app.Filter_amplitude, 'Z')
            app.Filter_phase = uiaxes(app.UIFigure);
            title(app.Filter_phase, '滤波器相频特性')
            xlabel(app.Filter_phase, 'f/Hz')
            ylabel(app.Filter_phase, '相位(\pi)')
            zlabel(app.Filter_phase, 'Z')
            app.Filter_phase.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Filter_design = uipanel(app.UIFigure);
            app.Filter_design.Title = '设计滤波器';
            app.Switch = uiswitch(app.Filter_design, 'toggle');
            app.Switch.Items = {'IIR', 'FIR'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'IIR';
            app.IIRPanel = uipanel(app.Filter_design);
            app.IIRPanel.Title = 'IIR';
            app.Label_2 = uilabel(app.IIRPanel);
            app.Label_2.Text = '滤波器型号';
            app.IIR_filterchoose = uidropdown(app.IIRPanel);
            app.IIR_filterchoose.Items = {'1.巴特沃斯滤波器', '2.切比雪夫Ⅰ型', '3.切比雪夫Ⅱ型', '4.椭圆滤波器'};
            app.IIR_filterchoose.ValueChangedFcn = createCallbackFcn(app, @IIR_filterchooseValueChanged, true);
            app.IIR_filterchoose.Value = '1.巴特沃斯滤波器';
            app.DropDown_3Label = uilabel(app.IIRPanel);
            app.DropDown_3Label.Text = '滤波器性质';
            app.IIR_choose = uidropdown(app.IIRPanel);
            app.IIR_choose.Items = {'1.低通', '2.高通', '3.带通', '4.带阻'};
            app.IIR_choose.ValueChangedFcn = createCallbackFcn(app, @IIR_chooseValueChanged, true);
            app.IIR_choose.Value = '1.低通';
            app.Label_7 = uilabel(app.IIRPanel);
            app.Label_7.Text = '设计方法';
            app.IIR_method = uidropdown(app.IIRPanel);
            app.IIR_method.Items = {'1.脉冲响应不变法', '2.双线性变换法'};
            app.IIR_method.ValueChangedFcn = createCallbackFcn(app, @IIR_methodValueChanged, true);
            app.IIR_method.Value = '1.脉冲响应不变法';
            app.IIRLabel = uilabel(app.IIRPanel);
            app.IIRLabel.Text = 'IIR滤波器参数';
            app.FsLabel = uilabel(app.IIRPanel);
            app.FsLabel.Text = '阻带最小衰减(Rs/dB)';
            app.IIR_Rs = uieditfield(app.IIRPanel, 'numeric');
            app.IIR_Rs.ValueChangedFcn = createCallbackFcn(app, @IIR_RsValueChanged, true);
            app.RpdBEditField_2Label = uilabel(app.IIRPanel);
            app.RpdBEditField_2Label.Text = '通带最大衰减(Rp/dB)';
            app.IIR_Rp = uieditfield(app.IIRPanel, 'numeric');
            app.IIR_Rp.ValueChangedFcn = createCallbackFcn(app, @IIR_RpValueChanged, true);
            app.FpHzEditFieldLabel = uilabel(app.IIRPanel);
            app.FpHzEditFieldLabel.Text = '通带截止频率(Fp/Hz)';
            app.IIR_Fp = uieditfield(app.IIRPanel, 'text');
            app.IIR_Fp.ValueChangedFcn = createCallbackFcn(app, @IIR_FpValueChanged, true);
            app.IIR_Fp.Value = '0';
            app.FpHzEditField_2Label_2 = uilabel(app.IIRPanel);
            app.FpHzEditField_2Label_2.Text = '阻带截止频率(Fs/Hz)';
            app.IIR_Fs = uieditfield(app.IIRPanel, 'text');
            app.IIR_Fs.ValueChangedFcn = createCallbackFcn(app, @IIR_FsValueChanged, true);
            app.IIR_Fs.Value = '0';
            app.FIRPanel = uipanel(app.Filter_design);
            app.FIRPanel.Title = 'FIR';
            app.DropDown_5Label = uilabel(app.FIRPanel);
            app.DropDown_5Label.Text = '设计方法';
            app.FIR_method = uidropdown(app.FIRPanel);
            app.FIR_method.Items = {'1.窗函数设计法', '2.等波纹最佳逼近法'};
            app.FIR_method.ValueChangedFcn = createCallbackFcn(app, @FIR_methodValueChanged, true);
            app.FIR_method.Value = '1.窗函数设计法';
            app.Label_12 = uilabel(app.FIRPanel);
            app.Label_12.Text = '窗类型';
            app.win_choose = uidropdown(app.FIRPanel);
            app.win_choose.Items = {'1.汉宁窗', '2.汉明窗', '3.布莱克曼窗', '4.矩形窗', '5.三角型窗', '6.凯塞窗'};
            app.win_choose.ValueChangedFcn = createCallbackFcn(app, @win_chooseValueChanged, true);
            app.win_choose.Value = '1.汉宁窗';
            app.DropDown_7Label = uilabel(app.FIRPanel);
            app.DropDown_7Label.Text = '滤波器性质';
            app.FIR_choose = uidropdown(app.FIRPanel);
            app.FIR_choose.Items = {'1.低通', '2.高通', '3.带通', '4.带阻'};
            app.FIR_choose.ValueChangedFcn = createCallbackFcn(app, @FIR_chooseValueChanged, true);
            app.FIR_choose.Value = '1.低通';
            app.FIRLabel = uilabel(app.FIRPanel);
            app.FIRLabel.Text = 'FIR滤波器参数';
            app.RsdBEditFieldLabel = uilabel(app.FIRPanel);
            app.RsdBEditFieldLabel.Text = '阻带最小衰减(Rs/dB)';
            app.FIR_Rs = uieditfield(app.FIRPanel, 'numeric');
            app.FIR_Rs.ValueChangedFcn = createCallbackFcn(app, @FIR_RsValueChanged, true);
            app.RpdBEditFieldLabel = uilabel(app.FIRPanel);
            app.RpdBEditFieldLabel.Text = '通带最大衰减(Rp/dB)';
            app.FIR_Rp = uieditfield(app.FIRPanel, 'numeric');
            app.FIR_Rp.ValueChangedFcn = createCallbackFcn(app, @FIR_RpValueChanged, true);
            app.FpHzEditFieldLabel_2 = uilabel(app.FIRPanel);
            app.FpHzEditFieldLabel_2.Text = '通带截止频率(Fp/Hz)';
            app.FIR_Fp = uieditfield(app.FIRPanel, 'text');
            app.FIR_Fp.ValueChangedFcn = createCallbackFcn(app, @FIR_FpValueChanged, true);
            app.FIR_Fp.Value = '0';
            app.FsHzEditFieldLabel = uilabel(app.FIRPanel);
            app.FsHzEditFieldLabel.Text = '阻带截止频率(Fs/Hz)';
            app.FIR_Fs = uieditfield(app.FIRPanel, 'text');
            app.FIR_Fs.ValueChangedFcn = createCallbackFcn(app, @FIR_FsValueChanged, true);
            app.FIR_Fs.Value = '0';
            app.get_filter = uibutton(app.Filter_design, 'push');
            app.get_filter.ButtonPushedFcn = createCallbackFcn(app, @get_filterButtonPushed, true);
            app.get_filter.Text = '创建';
            app.readin_music = uibutton(app.UIFigure, 'push');
            app.readin_music.ButtonPushedFcn = createCallbackFcn(app, @readin_musicButtonPushed, true);
            app.readin_music.Text = '导入音频';
            app.LOGO = uiimage(app.UIFigure);
            app.LOGO.ImageSource = fullfile(pathToMLAPP, 'owl.png');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '语音信号处理系统';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '音频信息';
            app.music_information = uitextarea(app.UIFigure);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '变声程度';
            app.change_voice = uislider(app.UIFigure);
            app.change_voice.Limits = [-10 10];
            app.change_voice.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.change_voice.ValueChangedFcn = createCallbackFcn(app, @change_voiceValueChanged, true);
            app.change_voice.MinorTicks = [-9.5 -8.5 -7.5 -6.5 -5.5 -4.5 -3.5 -2.5 -1.5 -0.5 0.5 1.5 2.5 3.5 4.5 5.5 6.5 7.5 8.5 9.5];
            app.play_music = uibutton(app.UIFigure, 'push');
            app.play_music.ButtonPushedFcn = createCallbackFcn(app, @play_musicButtonPushed, true);
            app.play_music.Text = '播放音频';
            app.ifok = uibutton(app.UIFigure, 'push');
            app.ifok.ButtonPushedFcn = createCallbackFcn(app, @ifokButtonPushed, true);
            app.ifok.Text = '确定参数';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '音量';
            app.voice_amplitude = uislider(app.UIFigure);
            app.voice_amplitude.Value = 50;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '基本处理';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '滤波器设计及处理';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sginal_processer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Mystartup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end