classdef Sginal_processer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        change_velocity         matlab.ui.control.Slider
        Label_17                matlab.ui.control.Label
        stop                    matlab.ui.control.Button
        hunyin                  matlab.ui.control.Button
        voice_amplitude         matlab.ui.control.Slider
        Label_15                matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        Button_12               matlab.ui.control.Button
        Button_11               matlab.ui.control.Button
        readin_music_2          matlab.ui.control.Button
        plus_noise              matlab.ui.container.Panel
        Button_10               matlab.ui.control.Button
        Button_9                matlab.ui.control.Button
        Button_8                matlab.ui.control.Button
        Button_7                matlab.ui.control.Button
        AM                      matlab.ui.container.Panel
        ButtonGroup             matlab.ui.container.ButtonGroup
        filterr                 matlab.ui.control.Button
        blackmann               matlab.ui.control.RadioButton
        rectwinn                matlab.ui.control.RadioButton
        buttordd                matlab.ui.control.RadioButton
        Button_2                matlab.ui.control.Button
        EditField_2             matlab.ui.control.NumericEditField
        Label_16                matlab.ui.control.Label
        Switch_2                matlab.ui.control.ToggleSwitch
        resampleok              matlab.ui.control.Button
        play_music_2            matlab.ui.control.Button
        resample_num            matlab.ui.control.NumericEditField
        Label_13                matlab.ui.control.Label
        voice_echo              matlab.ui.control.Slider
        Label_10                matlab.ui.control.Label
        change_voice            matlab.ui.control.Slider
        Label_9                 matlab.ui.control.Label
        Tab_2                   matlab.ui.container.Tab
        stop_2                  matlab.ui.control.Button
        play_music              matlab.ui.control.Button
        ifok                    matlab.ui.control.Button
        Filter_design           matlab.ui.container.Panel
        get_filter              matlab.ui.control.Button
        FIRPanel                matlab.ui.container.Panel
        FIR_Fs                  matlab.ui.control.EditField
        FsHzEditFieldLabel      matlab.ui.control.Label
        FIR_Fp                  matlab.ui.control.EditField
        FpHzEditFieldLabel_2    matlab.ui.control.Label
        FIR_Rp                  matlab.ui.control.NumericEditField
        RpdBEditFieldLabel      matlab.ui.control.Label
        FIR_Rs                  matlab.ui.control.NumericEditField
        RsdBEditFieldLabel      matlab.ui.control.Label
        FIRLabel                matlab.ui.control.Label
        FIR_choose              matlab.ui.control.DropDown
        DropDown_7Label         matlab.ui.control.Label
        win_choose              matlab.ui.control.DropDown
        Label_12                matlab.ui.control.Label
        FIR_method              matlab.ui.control.DropDown
        DropDown_5Label         matlab.ui.control.Label
        IIRPanel                matlab.ui.container.Panel
        IIR_Fs                  matlab.ui.control.EditField
        FpHzEditField_2Label_2  matlab.ui.control.Label
        IIR_Fp                  matlab.ui.control.EditField
        FpHzEditFieldLabel      matlab.ui.control.Label
        IIR_Rp                  matlab.ui.control.NumericEditField
        RpdBEditField_2Label    matlab.ui.control.Label
        IIR_Rs                  matlab.ui.control.NumericEditField
        FsLabel                 matlab.ui.control.Label
        IIRLabel                matlab.ui.control.Label
        IIR_method              matlab.ui.control.DropDown
        Label_7                 matlab.ui.control.Label
        IIR_choose              matlab.ui.control.DropDown
        DropDown_3Label         matlab.ui.control.Label
        IIR_filterchoose        matlab.ui.control.DropDown
        Label_2                 matlab.ui.control.Label
        Switch                  matlab.ui.control.ToggleSwitch
        Filter_phase            matlab.ui.control.UIAxes
        Filter_amplitude        matlab.ui.control.UIAxes
        voice_process           matlab.ui.container.Tab
        note_Label              matlab.ui.control.Label
        Button_16               matlab.ui.control.Button
        recognize_word          matlab.ui.control.Button
        SaveTextButton          matlab.ui.control.Button
        Button_13               matlab.ui.control.Button
        TextBox                 matlab.ui.control.TextArea
        TextAreaLabel           matlab.ui.control.Label
        music_information       matlab.ui.control.TextArea
        Label_6                 matlab.ui.control.Label
        Label_4                 matlab.ui.control.Label
        readin_music            matlab.ui.control.Button
        after_Spectrum          matlab.ui.control.UIAxes
        after_wave              matlab.ui.control.UIAxes
        before_Spectrum         matlab.ui.control.UIAxes
        before_wave             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        org_data;org_length;org_fulldata
        FS_music;
        filter_exist; % 检测是否设计了滤波器
        iir_method;
        iir_filterchoose;
        iir_choose;
        iir_Fp;iir_Fs;iir_Rp;iir_Rs;
        switchok;Bnum;A;
        fir_method;
        fir_winchoose;
        fir_choose;
        fir_Fp;fir_Fs;fir_Rp;fir_Rs;
        hnn;
        draw_t;draw_w;
        voicechange  % 检测是否进行变声设计
        resample_fs % 减采样的频率
        carry_fs%载波频率
        intofilterchoose
        rf%发射信号
        am_b;am_a;am_hn;
        noise_sound
        Zhu_fft;Tian_fft;len_N;
        Lisen_obj
        Property39 % Description
        Property43 % Description
    end
    properties (Access = private)
    audioFilePath % 用于存储上传的音频文件路径
    end
    properties (Access = public)
    filepath % 音频文件的路径
    file % 音频文件名
    recognizedText % 存储识别的文字内容
    end
    methods (Access = private)
        
        function Y = chvoice(app,y1,fs,p)
            y1=y1(:,1);%取一个声道
            f=round(p*fs);%f为2000？
            d=resample(y1,f,fs);      %时长整合使语音文件恢复原来时长
            W=400;
            Wov=W/2;        %overlap? Wov=200
            Kmax=W*2;       %Kmax?    Kmax=W=400
Wsim=Wov;       %Wsim     Wsim=200
xdecim=8;       %间隔
kdecim=2;
X=d';           %X为重采样数据
F=p;       %F是重采样频率/1000，就是扩大倍数
Ss=W-Wov;       %Ss为400-200 
xpts=size(X,2); %数据个数
ypts=round(xpts/F);%原数据个数
Y=zeros(1,ypts);%1*ypts的零矩阵
xfwin=(1:Wov)/(Wov+1);%（1-200）/201，归一化？
ovix=(1-Wov):0;%负数到0
newix=1:(W-Wov);%1~（400-200）
simix=(1:xdecim:Wsim)-Wsim;%1:8:200-200=-199:8:0
padX=[zeros(1,Wsim),X,zeros(1,Kmax+W-Wov)];%[200个0，重采样的数据，800+400-200个0]
Y(1:Wsim)=X(1:Wsim);    %取重采样后的前200个数据给Y
lastxpos=0;             %？
km=0;                   %？
    for ypos=Wsim:Ss:(ypts-W)       %ypos取值范围为200:200:原数据长度-400
        xpos=round(F*ypos);         %xpos每一次取值为2*ypos
        kmpred=km+(xpos-lastxpos);  %kmpred=0+现在的xpos-上一次xpos
        lastxpos=xpos;              %上一个xpos
        if(kmpred<=Kmax)            %如果差值小于等于Kmax，即小于等于800
            km=kmpred;              %km，修正量？就赋值为差值
        else                        %如果差值大于Kmax，即大于800
            ysim=Y(ypos+simix);     %ysim=重采样后的前200个数据中
            rxy=zeros(1,Kmax+1);    %创建一个长为Kmax+1的行向量    
            rxx=zeros(1,Kmax+1);    %创建一个长为Kmax+1的行向量  
            Kmin=0;                 %Kmin清0
            for k=Kmin:kdecim:Kmax  %k的取值范围为0:2:Kmax
                xsim=padX(Wsim+xpos+k+simix);%取200+2*200/2*400/...+0/2/4...+[-199:8:0]
                rxx(k+1)=norm(xsim);%xsim的向量模存入rxx(1/3/5/7/...)
                rxy(k+1)=(ysim*xsim');%1×25乘25×1，存入rxy(1/3/5/7/...)
            end
            Rxy=(rxx~=0).*rxy./(rxx+(rxx==0));%Rxy存
            km=min(find(Rxy==max(Rxy))-1);
        end
        xabs=xpos+km;
        Y(ypos+ovix)=((1-xfwin).*Y(ypos+ovix))+(xfwin.*padX(Wsim+xabs+ovix));
        Y(ypos+newix)=padX(Wsim+xabs+newix);
    end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Mystartup(app)
            %视图初始化
            app.FIR_Rp.Enable="off";
            app.FIR_Rs.Enable="off";
            app.FIR_Fp.Enable="off";
            app.FIR_Fs.Enable="off";
            app.FIR_choose.Enable="off";
            app.win_choose.Enable="off";
            app.FIR_method.Enable="off";
            app.filterr.Enable="off";
            app.blackmann.Enable="off";
            app.rectwinn.Enable="off";
            app.buttordd.Enable="off";
            %变量初始化
            app.filter_exist=0;
            app.iir_method=1;
            app.iir_filterchoose=1;
            app.iir_choose=1;
            app.fir_method=1;
            app.fir_winchoose=1;
            app.fir_choose=1;
            app.FS_music=44100;
            app.ButtonGroup.SelectedObject=app.buttordd;
            app.intofilterchoose=0;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'IIR')
                app.FIR_Rp.Enable="off";
                app.FIR_Rs.Enable="off";
                app.FIR_Fp.Enable="off";
                app.FIR_Fs.Enable="off";
                app.FIR_choose.Enable="off";
                app.win_choose.Enable="off";
                app.FIR_method.Enable="off";

                app.IIR_Rp.Enable="on";
                app.IIR_Rs.Enable="on";
                app.IIR_Fp.Enable="on";
                app.IIR_Fs.Enable="on";
                app.IIR_choose.Enable="on";
                app.IIR_filterchoose.Enable="on";
                app.IIR_method.Enable="on";
            else
                % app.FIR_Rp.Enable="on";
                % app.FIR_Rs.Enable="on";
                app.FIR_Fp.Enable="on";
                app.FIR_Fs.Enable="on";
                app.FIR_choose.Enable="on";
                app.win_choose.Enable="on";
                app.FIR_method.Enable="on";

                app.IIR_Rp.Enable="off";
                app.IIR_Rs.Enable="off";
                app.IIR_Fp.Enable="off";
                app.IIR_Fs.Enable="off";
                app.IIR_choose.Enable="off";
                app.IIR_filterchoose.Enable="off";
                app.IIR_method.Enable="off";
            end
        end

        % Button pushed function: readin_music
        function readin_musicButtonPushed(app, event)
% 上传音频文件的代码
[file, filepath] = uigetfile({'*.mp3'; '*.wav'}, '选择音频文件');
if isequal(file, 0) || isequal(filepath, 0)
    % 用户没有选择文件
    uialert(app.UIFigure, '未选择任何音频文件！', '提示');
    return;
end

try
    % 保存文件路径到 app 属性
    app.audioFilePath = fullfile(filepath, file); % 保存文件的完整路径

    % 读取音频文件数据
    [org_music, fs_music] = audioread(app.audioFilePath);
    len = length(org_music);
    t = 0:1/fs_music:(len-1)/fs_music;
    app.draw_t = t;
    app.org_length = len;

    fp = 0:fs_music/len:fs_music*(len-1)/len;
    wpic = 2 * fp / fs_music; % 归一化
    app.draw_w = wpic;

    Music_information = strcat("The audio signal sampling points are ", ...
        num2str(len), ". The sampling frequency is ", num2str(fs_music), " Hz.");
    app.music_information.Value = Music_information;

    app.FS_music = fs_music;
    app.org_data = org_music(:, 1); % 仅使用第一个声道
    app.org_fulldata = org_music;  % 保存音频数值数据

    % 绘制导入音频波形及频谱
    plot(app.before_wave, t, org_music(:, 1));
    app.before_wave.XTick = 0:5:20;

    org_fft = fft(org_music(:, 1));
    plot(app.before_Spectrum, wpic, abs(org_fft));

    % 提示成功上传
    uialert(app.UIFigure, '音频文件上传成功！', '成功');

catch ME
    % 捕获错误
    uialert(app.UIFigure, ['上传音频文件时发生错误：', ME.message], '错误');
end
        end

        % Button pushed function: get_filter
        function get_filterButtonPushed(app, event)
            %设计滤波器，显示滤波器幅频特性和相频特性。
            app.filter_exist = 1;
            % assignin("base","iir_Fp",app.iir_Fp);assignin("base","iir_Fs",app.iir_Fs);
            % assignin("base","iir_Rp",app.iir_Rp);assignin("base","iir_Rs",app.iir_Rs);
            if(strcmp(app.IIR_method.Enable,"on"))
                switch(app.iir_method)
                    case 1%脉冲响应不变法
                        switch(app.iir_filterchoose)
                            case 1
                                switch(app.iir_choose)
                                    case 1
                                        [BU_N1,BU_Wn1]=buttord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N1,BU_Wn1,'low');
                                    case 2
                                        [BU_N2,BU_Wn2]=buttord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N2,BU_Wn2,'high');
                                    case 3
                                        [BU_N3,BU_Wn3]=buttord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N3,BU_Wn3,'bandpass');
                                    case 4
                                        [BU_N4,BU_Wn4]=buttord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N4,BU_Wn4,'stop');
                                end
                            case 2
                                switch(app.iir_choose)
                                    case 1
                                        [CH1_N1,CH1_Wn1]=cheb1ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N1,app.iir_Rp,CH1_Wn1,'low');
                                    case 2
                                        [CH1_N2,CH1_Wn2]=cheb1ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N2,app.iir_Rp,CH1_Wn2,'high');
                                    case 3
                                        [CH1_N3,CH1_Wn3]=cheb1ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N3,app.iir_Rp,CH1_Wn3,'bandpass');
                                    case 4
                                        [CH1_N4,CH1_Wn4]=cheb1ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N4,app.iir_Rp,CH1_Wn4,'stop');
                                end
                            case 3
                                switch(app.iir_choose)
                                    case 1
                                        [CH2_N1,CH2_Wn1]=cheb2ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N1,app.iir_Rp,CH2_Wn1,'low');
                                    case 2
                                        [CH2_N2,CH2_Wn2]=cheb2ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N2,app.iir_Rp,CH2_Wn2,'high');
                                    case 3
                                        [CH2_N3,CH2_Wn3]=cheb2ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N3,app.iir_Rp,CH2_Wn3,'bandpass');
                                    case 4
                                        [CH2_N4,CH2_Wn4]=cheb2ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N4,app.iir_Rp,CH2_Wn4,'stop');
                                end
                            case 4
                                switch(app.iir_choose)
                                    case 1
                                        [EL_N1,EL_Wn1]=ellipord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N1,app.iir_Rp,app.iir_Rs,EL_Wn1,'low');
                                    case 2
                                        [EL_N2,EL_Wn2]=ellipord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N2,app.iir_Rp,app.iir_Rs,EL_Wn2,'high');
                                    case 3
                                        [EL_N3,EL_Wn3]=ellipord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N3,app.iir_Rp,app.iir_Rs,EL_Wn3,'bandpass');
                                    case 4
                                        [EL_N4,EL_Wn4]=ellipord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N4,app.iir_Rp,app.iir_Rs,EL_Wn4,'stop');
                                end
                        end
                    case 2%双线性变换法
                        wp=(2*pi.*app.iir_Fp)./app.FS_music;ws=(2*pi.*app.iir_Fs)./app.FS_music;
                        twp=2.*tan(wp./2).*app.FS_music;tws=2.*tan(ws./2).*app.FS_music;%预畸变
                        assignin("base","iir_twp",twp);assignin("base","iir_tws",tws);
                        switch(app.iir_filterchoose)
                            case 1
                                switch(app.iir_choose)
                                    case 1
                                        [BU_N1,BU_Wn1]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,'s');
                                        [BU_B1,BU_A1]=butter(BU_N1,BU_Wn1,'low','s');
                                        [b,a]=bilinear(BU_B1,BU_A1,app.FS_music);
                                    case 2
                                        [BU_N2,BU_Wn2]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,'s');
                                        [BU_B2,BU_A2]=butter(BU_N2,BU_Wn2,'high','s');
                                        [b,a]=bilinear(BU_B2,BU_A2,app.FS_music);
                                    case 3
                                        [BU_N3,BU_Wn3]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [BU_B3,BU_A3]=butter(BU_N3,BU_Wn3,'bandpass','s');
                                        [b,a]=bilinear(BU_B3,BU_A3,app.FS_music);
                                    case 4
                                        [BU_N4,BU_Wn4]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [BU_B4,BU_A4]=butter(BU_N4,BU_Wn4,'stop','s');
                                        [b,a]=bilinear(BU_B4,BU_A4,app.FS_music);
                                end
                            case 2
                                switch(app.iir_choose)
                                    case 1
                                        [CH1_N1,CH1_Wn1]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B1,CH1_A1]=cheby1(CH1_N1,app.iir_Rp,CH1_Wn1,'low','s');
                                        [b,a]=bilinear(CH1_B1,CH1_A1,app.FS_music);
                                    case 2
                                        [CH1_N2,CH1_Wn2]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B2,CH1_A2]=cheby1(CH1_N2,app.iir_Rp,CH1_Wn2,'high','s');
                                        [b,a]=bilinear(CH1_B2,CH1_A2,app.FS_music);
                                    case 3
                                        [CH1_N3,CH1_Wn3]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B3,CH1_A3]=cheby1(CH1_N3,app.iir_Rp,CH1_Wn3,'bandpass','s');
                                        [b,a]=bilinear(CH1_B3,CH1_A3,app.FS_music);
                                    case 4
                                        [CH1_N4,CH1_Wn4]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B4,CH1_A4]=cheby1(CH1_N4,app.iir_Rp,CH1_Wn4,'stop','s');
                                        [b,a]=bilinear(CH1_B4,CH1_A4,app.FS_music);
                                end
                            case 3
                                switch(app.iir_choose)
                                    case 1
                                        [CH2_N1,CH2_Wn1]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B1,CH2_A1]=cheby2(CH2_N1,app.iir_Rp,CH2_Wn1,'low','s');
                                        [b,a]=bilinear(CH2_B1,CH2_A1,app.FS_music);
                                    case 2
                                        [CH2_N2,CH2_Wn2]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B2,CH2_A2]=cheby2(CH2_N2,app.iir_Rp,CH2_Wn2,'high','s');
                                        [b,a]=bilinear(CH2_B2,CH2_A2,app.FS_music);
                                    case 3
                                        [CH2_N3,CH2_Wn3]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B3,CH2_A3]=cheby2(CH2_N3,app.iir_Rp,CH2_Wn3,'bandpass','s');
                                        [b,a]=bilinear(CH2_B3,CH2_A3,app.FS_music);
                                    case 4
                                        [CH2_N4,CH2_Wn4]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B4,CH2_A4]=cheby2(CH2_N4,app.iir_Rp,CH2_Wn4,'stop','s');
                                        [b,a]=bilinear(CH2_B4,CH2_A4,app.FS_music);
                                end
                            case 4
                                switch(app.iir_choose)
                                    case 1
                                        [EL_N1,EL_Wn1]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B1,EL_A1]=ellip(EL_N1,app.iir_Rp,app.iir_Rs,EL_Wn1,'low','s');
                                        [b,a]=bilinear(EL_B1,EL_A1,app.FS_music);
                                    case 2
                                        [EL_N2,EL_Wn2]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B2,EL_A2]=ellip(EL_N2,app.iir_Rp,app.iir_Rs,EL_Wn2,'high','s');
                                        [b,a]=bilinear(EL_B2,EL_A2,app.FS_music);
                                    case 3
                                        [EL_N3,EL_Wn3]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B3,EL_A3]=ellip(EL_N3,app.iir_Rp,app.iir_Rs,EL_Wn3,'bandpass','s');
                                        [b,a]=bilinear(EL_B3,EL_A3,app.FS_music);
                                    case 4
                                        [EL_N4,EL_Wn4]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B4,EL_A4]=ellip(EL_N4,app.iir_Rp,app.iir_Rs,EL_Wn4,'stop','s');
                                        [b,a]=bilinear(EL_B4,EL_A4,app.FS_music);
                                end
                        end
                end
                [Hw,w]=freqz(b,a,1024);
                fpic=w./max(w).*app.FS_music./2;
                plot(app.Filter_amplitude,fpic,20*log10(abs(Hw)));xlim(app.Filter_amplitude,[0 app.FS_music/2]);
                plot(app.Filter_phase,fpic,angle(Hw));app.Filter_phase.XTick=app.Filter_amplitude.XTick;
                xlim(app.Filter_phase,[0 app.FS_music/2]);
                app.switchok=1;app.Bnum=b;app.A=a;
            else
                % assignin("base","Nb",Nb);
                switch(app.fir_method)
                    case 1%窗函数设计法
                        wc=(app.fir_Fp+app.fir_Fs)/app.FS_music; %理想低通滤波器截止频率(关于 pi 归一化）
                        B=abs(2*pi*(app.fir_Fs-app.fir_Fp)/app.FS_music); %过渡带宽度指标
                        Nb=ceil(11*pi/B); %blackman 窗的长度 N
                        switch(app.fir_winchoose)
                            case 1%汉宁窗
                                switch(app.fir_choose)
                                    case 1
                                        %msgbox("1");
                                        hn=fir1(Nb-1,wc,"low",hann(Nb));
                                    case 2
                                        % y=hann(Nb);
                                        % assignin("base","hannNb",y);
                                        hn=fir1(Nb-1,wc,"high",hann(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",hann(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",hann(Nb));
                                end
                            case 2%汉明窗
                                switch(app.fir_choose)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",hamming(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",hamming(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",hamming(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",hamming(Nb));
                                end
                            case 3%布莱克曼窗
                                switch(app.fir_choose)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",blackman(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",blackman(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",blackman(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",blackman(Nb));
                                end
                            case 4%矩形窗
                                switch(app.fir_choose)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",rectwin(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",rectwin(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",rectwin(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",rectwin(Nb));
                                end
                            case 5%三角型窗
                                switch(app.fir_choose)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",triang(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",triang(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",triang(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",triang(Nb));
                                end
                            case 6%凯赛窗
                                switch(app.fir_choose)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",kaiser(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",kaiser(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",kaiser(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",kaiser(Nb));
                                end
                        end

                    case 2% 等波纹最佳逼近
                        switch(app.fir_choose)
                            case 1%低通
                                dev=[(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20)];
                                fb=[app.fir_Fp,app.fir_Fs];m=[1,0];% 确定 remezord 函数所需参数 f,m
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 2%高通
                                dev=[10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1)];
                                fb=[app.fir_Fs,app.fir_Fp];m=[0,1];% 确定 remezord 函数所需参数 f,m
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 3%带通
                                dev=[10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20)];
                                fb=[app.fir_Fs(1,1),app.fir_Fp(1,1),app.fir_Fp(1,2),app.fir_Fs(1,2)];m=[0,1,0];
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 4%带阻
                                dev=[(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1)];
                                fb=[app.fir_Fp(1,1),app.fir_Fs(1,1),app.fir_Fs(1,2),app.fir_Fp(1,2)];m=[1,0,1];
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                        end
                end
                Hw=fft(hn,1024); % 求设计的滤波器频率特性
                db_Hw=20*log(abs(Hw))/log(10);
                firw=0:1:1023;firw=firw./1024*app.FS_music;
                plot(app.Filter_amplitude,firw,db_Hw);axis(app.Filter_amplitude,[0,22050,-200,5]);
                plot(app.Filter_phase,firw,angle(Hw));app.Filter_phase.XTick=app.Filter_amplitude.XTick;
                xlim(app.Filter_phase,[0 app.FS_music/2]);
                app.hnn=hn;app.switchok=2;
            end
        end

        % Button pushed function: ifok
        function ifokButtonPushed(app, event)
            %显示波形和频谱图，判断输出哪一个音频数据。
            x=app.org_data;
            % if(app.voicechange == 1)
            % 
            %     %app.Lisen_obj=audioplayer(y,app.FS_music);
            %     plot(app.after_wave,app.draw_t,y);app.after_wave.XTick=0:5:20;
            %     plot(app.after_Spectrum,app.draw_w,abs(Y));
            % else    
                % if(app.filter_exist == 1)
                    switch(app.switchok)
                        case 1
                            msgbox('use iir');
                            y=filter(app.Bnum,app.A,x);Y=fft(y);
                            app.Lisen_obj=audioplayer(y,app.FS_music);
                            plot(app.after_wave,app.draw_t,y);app.after_wave.XTick=0:5:20;
                            plot(app.after_Spectrum,app.draw_w,abs(Y));
                        case 2
                            msgbox('use fir');
                            y=fftfilt(app.hnn,x,1000);Y=fft(y);
                            app.Lisen_obj=audioplayer(y,app.FS_music);
                            plot(app.after_wave,app.draw_t,y);app.after_wave.XTick=0:5:20;
                            plot(app.after_Spectrum,app.draw_w,abs(Y));
                    end
                % end
        end

        % Value changed function: change_voice
        function change_voiceValueChanged(app, event)
            value = app.change_voice.Value;
            change_v=chvoice(app,app.org_data,app.FS_music,(value+20)/20);%最后一个大于1则基频变低，小于1则基频变高
            cv_fft=fft(change_v);
            t=0:1/app.FS_music:(length(change_v)-1)/app.FS_music;
            fp=0:app.FS_music/length(change_v):app.FS_music*(length(change_v)-1)/length(change_v);w=2*fp/app.FS_music;
            plot(app.after_wave,t,change_v);app.after_wave.XTick=0:5:20;
            plot(app.after_Spectrum,w,abs(cv_fft));
            app.Lisen_obj=audioplayer(change_v,app.FS_music);
        end

        % Value changed function: IIR_method
        function IIR_methodValueChanged(app, event)
            value = app.IIR_method.Value;
            switch (value)
                case '1.脉冲响应不变法'
                    app.iir_method=1;
                    assignin('base','r_method',app.iir_method);
                case '2.双线性变换法'
                    app.iir_method=2;
                    assignin('base','r_method',app.iir_method);
            end
        end

        % Value changed function: IIR_Fp
        function IIR_FpValueChanged(app, event)
            value = app.IIR_Fp.Value;
            app.iir_Fp=str2num(value);
        end

        % Value changed function: IIR_filterchoose
        function IIR_filterchooseValueChanged(app, event)
            value = app.IIR_filterchoose.Value;
            switch (value)
                case '1.巴特沃斯滤波器'
                    app.iir_filterchoose=1;
                case '2.切比雪夫Ⅰ型'
                    app.iir_filterchoose=2;
                case '3.切比雪夫Ⅱ型'
                    app.iir_filterchoose=3;
                case '4.椭圆滤波器'
                    app.iir_filterchoose=4;
            end
        end

        % Value changed function: IIR_choose
        function IIR_chooseValueChanged(app, event)
            value = app.IIR_choose.Value;
            switch (value)
                case '1.低通'
                    app.iir_choose=1;
                case '2.高通'
                    app.iir_choose=2;
                case '3.带通'
                    app.iir_choose=3;
                    msgbox("带通滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                case '4.带阻'
                    app.iir_choose=4;
                    msgbox("带阻滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
            end
        end

        % Value changed function: IIR_Fs
        function IIR_FsValueChanged(app, event)
            value = app.IIR_Fs.Value;
            app.iir_Fs=str2num(value);
        end

        % Value changed function: IIR_Rp
        function IIR_RpValueChanged(app, event)
            value = app.IIR_Rp.Value;
            app.iir_Rp=value;
        end

        % Value changed function: IIR_Rs
        function IIR_RsValueChanged(app, event)
            value = app.IIR_Rs.Value;
            app.iir_Rs=value;
        end

        % Value changed function: FIR_method
        function FIR_methodValueChanged(app, event)
            value = app.FIR_method.Value;
            switch (value)
                case '1.窗函数设计法'
                    app.fir_method=1;
                    app.win_choose.Enable="on";
                    app.FIR_Rp.Enable="off";
                    app.FIR_Rs.Enable="off";
                case '2.等波纹最佳逼近法'
                    app.fir_method=2;
                    app.win_choose.Enable="off";
                    app.FIR_Rp.Enable="on";
                    app.FIR_Rs.Enable="on";
            end
        end

        % Value changed function: win_choose
        function win_chooseValueChanged(app, event)
            value = app.win_choose.Value;
            switch (value)
                case '1.汉宁窗'
                    app.fir_winchoose=1;
                case '2.汉明窗'
                    app.fir_winchoose=2;
                case '3.布莱克曼窗'
                    app.fir_winchoose=3;
                case '4.矩形窗'
                    app.fir_winchoose=4;
                case '5.三角型窗'
                    app.fir_winchoose=5;
                case '6.凯塞窗'
                    app.fir_winchoose=6;
            end
        end

        % Value changed function: FIR_choose
        function FIR_chooseValueChanged(app, event)
            value = app.FIR_choose.Value;
            switch (value)
                case '1.低通'
                    app.fir_choose=1;
                case '2.高通'
                    app.fir_choose=2;
                case '3.带通'
                    app.fir_choose=3;
                    switch(app.FIR_method.Value)
                        case '1.窗函数设计法'
                            msgbox("带通滤波器的通带上下限截止频率分别写在通带截止频率和阻带截止频率处。","提示",'help');
                        case '2.等波纹最佳逼近法'
                            msgbox("带通滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                    end
                case '4.带阻'
                    app.fir_choose=4;
                    switch(app.FIR_method.Value)
                        case '1.窗函数设计法'
                            msgbox("带阻滤波器的通带上下限截止频率分别写在通带截止频率和阻带截止频率处。","提示",'help');
                        case '2.等波纹最佳逼近法'
                            msgbox("带阻滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                    end
            end
        end

        % Value changed function: FIR_Fp
        function FIR_FpValueChanged(app, event)
            value = app.FIR_Fp.Value;
            app.fir_Fp=str2num(value);
        end

        % Value changed function: FIR_Fs
        function FIR_FsValueChanged(app, event)
            value = app.FIR_Fs.Value;
            app.fir_Fs=str2num(value);
        end

        % Value changed function: FIR_Rp
        function FIR_RpValueChanged(app, event)
            value = app.FIR_Rp.Value;
            app.fir_Rp=value;
        end

        % Value changed function: FIR_Rs
        function FIR_RsValueChanged(app, event)
            value = app.FIR_Rs.Value;
            app.fir_Rs=value;
        end

        % Button pushed function: play_music
        function play_musicButtonPushed(app, event)
            play(app.Lisen_obj);
        end

        % Value changed function: voice_amplitude
        function voice_amplitudeValueChanged(app, event)
            value = app.voice_amplitude.Value;
            org=app.org_data;ch_data=org(:,1).*(value/10);
            plot(app.after_wave,app.draw_t,ch_data);app.after_wave.XTick=0:5:20;
            Fft=fft(ch_data);plot(app.after_Spectrum,app.draw_w,abs(Fft));
            app.Lisen_obj=audioplayer(ch_data,app.FS_music);
        end

        % Button pushed function: hunyin
        function hunyinButtonPushed(app, event)
            [file,filepath]=uigetfile({'*.wav';'*.mp3'});
            filename=fullfile(filepath,file);
            [org_music,fs_music]=audioread(filename);
            if length(org_music)<length(app.org_fulldata)%x长度小于y时
                co_sound = app.org_data;
                for i = 1:1:length(org_music)
                    co_sound(i,1) = co_sound(i,1)+org_music(i,1);
                    co_sound(i,2) = co_sound(i,2)+org_music(i,2);
                end
            else%x长度大于y时
                co_sound = org_music;
                for i = 1:1:length(app.org_data)
                    co_sound(i,1) = co_sound(i,1)+app.org_fulldata(i,1);
                    co_sound(i,2) = co_sound(i,2)+app.org_fulldata(i,2);
                end
            end
            t=0:1/app.FS_music:(length(co_sound(:,1))-1)/app.FS_music;
            fp=0:app.FS_music/length(co_sound(:,1)):app.FS_music*(length(co_sound(:,1))-1)/length(co_sound(:,1));
            w=2*fp/app.FS_music;%归一化
            plot(app.after_wave,t,co_sound(:,1));app.after_wave.XTick=0:5:20;app.after_wave.XLim=[0,20];
            Fft=fft(co_sound(:,1));plot(app.after_Spectrum,w,abs(Fft));
            app.Lisen_obj=audioplayer(co_sound,app.FS_music);
        end

        % Value changed function: voice_echo
        function voice_echoValueChanged(app, event)
            value = app.voice_echo.Value;
            cut_sound = app.org_data;
            zeroo = zeros(20000*round(value/50),1);
            temp1 = [cut_sound;zeroo;zeroo];
            temp2 = [zeroo;cut_sound*0.1;zeroo];
            temp3 = [zeroo;zeroo;cut_sound*0.01];
            delay_sound = temp1+temp2+temp3;
            t=0:1/app.FS_music:(length(delay_sound)-1)/app.FS_music;
            plot(app.after_wave,t,delay_sound);app.after_wave.XTick=0:5:20;app.after_wave.XLim=[0,20];
            fp=0:app.FS_music/length(delay_sound):app.FS_music*(length(delay_sound)-1)/length(delay_sound);
            wpic=2*fp/app.FS_music;%归一化
            Fft=fft(delay_sound);plot(app.after_Spectrum,wpic,abs(Fft));
            app.Lisen_obj=audioplayer(delay_sound,app.FS_music);
        end

        % Value changed function: resample_num
        function resample_numValueChanged(app, event)
            value = app.resample_num.Value;
            app.resample_fs=value;
        end

        % Button pushed function: resampleok
        function resampleokButtonPushed(app, event)
            res_sound=resample(app.org_data,app.resample_fs,app.FS_music);
            t=0:1/app.resample_fs:(length(res_sound)-1)/app.resample_fs;
            fp=0:app.resample_fs/length(res_sound):app.resample_fs*(length(res_sound)-1)/length(res_sound);
            w=2*fp/app.resample_fs;%归一化
            plot(app.after_wave,t,res_sound);app.after_wave.XTick=0:5:20;
            Fft=fft(res_sound);plot(app.after_Spectrum,w,abs(Fft));
            app.Lisen_obj=audioplayer(res_sound,app.resample_fs);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.carry_fs=value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            carry=cos(pi*2*app.carry_fs*app.draw_t);
            app.rf=(app.org_data).*carry';
            plot(app.after_wave,app.draw_t,app.rf);app.after_wave.XTick=0:5:20;
            Fft=fft(app.rf);plot(app.after_Spectrum,app.draw_w,abs(Fft));
            app.Lisen_obj=audioplayer(app.rf,app.FS_music);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if strcmp(value,'调制')
                app.filterr.Enable="off";
                app.blackmann.Enable="off";
                app.rectwinn.Enable="off";
                app.buttordd.Enable="off";

                app.Button_2.Enable="on";
                app.EditField_2.Enable="on";
            else
                app.filterr.Enable="on";
                app.blackmann.Enable="on";
                app.rectwinn.Enable="on";
                app.buttordd.Enable="on";

                app.Button_2.Enable="off";
                app.EditField_2.Enable="off";
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.intofilterchoose=1;
            wp=6000/(app.FS_music/2);%通带截止频率，取0-10kHz中间的值，并对其归一化
            ws=9000/(app.FS_music/2);%阻带截止频率，取0-10kHz中间的值，并对其归一化
            alpha_p=0.1;%通带允许最大衰减为  db
            alpha_s=50;%阻带允许最小衰减为  db
            switch (selectedButton)
                case app.buttordd
                    [N1,wc1]=buttord(wp,ws,alpha_p,alpha_s);%获取阶数和截止频率
                    [app.am_b,app.am_a]=butter(N1,wc1,'low');%获得转移函数系数
                case app.rectwinn
                    wc=wp+ws;
                    B=2*pi*(ws-wp); %过渡带宽度指标
                    Nb=ceil(11*pi/B); %blackman 窗的长度 N
                    app.am_hn=fir1(Nb-1,wc,rectwin(Nb));
                case app.blackmann
                    wc=wp+ws;
                    B=2*pi*(ws-wp); %过渡带宽度指标
                    Nb=ceil(11*pi/B); %blackman 窗的长度 N
                    app.am_hn=fir1(Nb-1,wc,blackman(Nb));
            end
        end

        % Button pushed function: filterr
        function filterrButtonPushed(app, event)
            carry=cos(pi*2*app.carry_fs*app.draw_t);
            rec_=app.rf.*carry';
            if(app.ButtonGroup.SelectedObject==app.buttordd)
                if(app.intofilterchoose==0)
                    wp1=6000/(app.FS_music/2);%通带截止频率，取0-10kHz中间的值，并对其归一化
                    ws1=9000/(app.FS_music/2);%阻带截止频率，取0-10kHz中间的值，并对其归一化
                    alpha_p=0.1;%通带允许最大衰减为  db
                    alpha_s=50;%阻带允许最小衰减为  db
                    [N1,wc1]=buttord(wp1,ws1,alpha_p,alpha_s);%获取阶数和截止频率
                    [app.am_b,app.am_a]=butter(N1,wc1,'low');%获得转移函数系数
                end
                r_rec=filter(app.am_b,app.am_a,rec_);
            else
                r_rec=fftfilt(app.am_hn,rec_,app.org_length);
            end
            plot(app.after_wave,app.draw_t,r_rec);app.after_wave.XTick=0:5:20;
            Fft=fft(r_rec);plot(app.after_Spectrum,app.draw_w,abs(Fft));
            app.Lisen_obj=audioplayer(r_rec,app.FS_music);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            
            noise=0.05*(cos(2*pi*3000*app.draw_t)+cos(2*pi*5000*app.draw_t)+cos(2*pi*8000*app.draw_t));
            app.noise_sound=app.org_data+noise';ns_fft=fft(app.noise_sound);
            plot(app.after_wave,app.draw_t,app.noise_sound);app.after_wave.XTick=0:5:20;
            plot(app.after_Spectrum,app.draw_w,abs(ns_fft));
            app.Lisen_obj=audioplayer(app.noise_sound,app.FS_music);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            wp=0.1;ws=0.13;Rp=0.1;Rs=30;%3000/22050=0.136
            [N,wn]=buttord(wp,ws,Rp,Rs);%求低通滤波器的阶数和截止频率
            [b,a]=butter(N,wn);%求频率响应的参数
            fNp=filter(b,a,app.noise_sound);fNp_fft=fft(fNp);%求滤三余弦混合噪声后的信号
            plot(app.after_wave,app.draw_t,fNp);app.after_wave.XTick=0:5:20;
            plot(app.after_Spectrum,app.draw_w,abs(fNp_fft));
            app.Lisen_obj=audioplayer(fNp,app.FS_music);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            noise_w=0.5*rand(app.org_length,1);nwfft=fft(noise_w);nwfft(1,1)=0;
            app.noise_sound=app.org_data+noise_w;nws_fft=fft(app.noise_sound);nws_fft(1,1)=0;
            plot(app.after_wave,app.draw_t,app.noise_sound);app.after_wave.XTick=0:5:20;
            plot(app.after_Spectrum,app.draw_w,abs(nws_fft));
            app.Lisen_obj=audioplayer(app.noise_sound,app.FS_music);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            wp=0.1;ws=0.13;Rp=0.1;Rs=30;%3000/22050=0.136
            [N,wn]=buttord(wp,ws,Rp,Rs);%求低通滤波器的阶数和截止频率
            [b,a]=butter(N,wn);%求频率响应的参数
            fNwp=filter(b,a,app.noise_sound);fNwp_fft=fft(fNwp);%求滤三余弦混合噪声后的信号
            plot(app.after_wave,app.draw_t,fNwp);app.after_wave.XTick=0:5:20;fNwp_fft(1,1)=0;
            plot(app.after_Spectrum,app.draw_w,abs(fNwp_fft));
            app.Lisen_obj=audioplayer(fNwp,app.FS_music);
        end

        % Button pushed function: readin_music_2
        function readin_music_2ButtonPushed(app, event)
            [file,filepath]=uigetfile({'*.mp3';'*.wav'});
            filename=fullfile(filepath,file);
            [Tian_sound,fs_music]=audioread(filename);
            cut1=app.org_data(1:400000);cut2=Tian_sound(1:400000);app.len_N=length(cut1);
            app.Zhu_fft=fft(app.org_data(:,1),app.len_N);app.Tian_fft=fft(Tian_sound(:,1),app.len_N);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            cros1=abs(app.Zhu_fft).*exp(1i*angle(app.Tian_fft));%信号1的幅度谱和信号2的相位谱交叉
            CoZhu=real(ifft(cros1,app.len_N));
            t=0:1/app.FS_music:(app.len_N-1)/app.FS_music;
            fp=0:app.FS_music/app.len_N:app.FS_music*(app.len_N-1)/app.len_N;
            w=2*fp/app.FS_music;%归一化
            plot(app.after_wave,t,CoZhu);app.after_wave.XTick=0:5:20;
            plot(app.after_Spectrum,w,abs(cros1));
            app.Lisen_obj=audioplayer(CoZhu,app.FS_music);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            cros2=abs(app.Tian_fft).*exp(1i*angle(app.Zhu_fft));%信号2的幅度谱和信号1的相位谱交叉
            CoTian=real(ifft(cros2,app.len_N));%逆变换
            t=0:1/app.FS_music:(app.len_N-1)/app.FS_music;
            fp=0:app.FS_music/app.len_N:app.FS_music*(app.len_N-1)/app.len_N;
            w=2*fp/app.FS_music;%归一化
            plot(app.after_wave,t,CoTian);app.after_wave.XTick=0:5:20;
            plot(app.after_Spectrum,w,abs(cros2));
            app.Lisen_obj=audioplayer(CoTian,app.FS_music);
        end

        % Button pushed function: play_music_2
        function play_music_2ButtonPushed(app, event)
            play(app.Lisen_obj);
        end

        % Button pushed function: stop
        function stopButtonPushed(app, event)
            pause(app.Lisen_obj);
        end

        % Button pushed function: stop_2
        function stop_2ButtonPushed(app, event)
            pause(app.Lisen_obj);
        end

        % Value changed function: change_velocity
        function change_velocityValueChanged(app, event)
            value = app.change_velocity.Value;
            app.Lisen_obj=audioplayer(app.org_data,(value+20)/20*app.FS_music);
        end

        % Button pushed function: recognize_word
        function recognize_wordButtonPushed(app, event)
% 确保已上传音频文件并保存文件路径
if isempty(app.audioFilePath)
    uialert(app.UIFigure, '请先上传音频文件！', '提示');
    return;
end

% 定义 Python 环境和脚本路径
pythonExePath = '/opt/anaconda3/envs/pytorch/bin/python'; % 替换为你的 Python 路径
pythonScriptPath = '/Users/Apple/Documents/code_document/python_codes/whisper_model/whisper_main.py'; % 替换为你的 Python 脚本路径

% 调用 Python 脚本进行语音识别
try
    % 构建命令
    command = sprintf('"%s" "%s" "%s"', pythonExePath, pythonScriptPath, app.audioFilePath);

    % 调用系统命令并捕获输出
    [status, result] = system(command);

    % 检查执行状态
    if status == 0
        % 将返回的文本结果显示在应用中
        app.TextBox.Value = strtrim(result); % 去掉多余换行符和空格
        uialert(app.UIFigure, '语音识别完成！', '成功');
    else
        % 显示错误信息
        uialert(app.UIFigure, ['语音识别失败：', result], '错误');
    end
catch ME
    % 捕获并处理其他错误
    uialert(app.UIFigure, ['运行语音识别时发生错误：', ME.message], '错误');
end
        end

        % Button pushed function: SaveTextButton
        function SaveTextButtonPushed(app, event)

    % 检查是否已经有识别结果
    if isempty(app.recognizedText)
        uialert(app.UIFigure, '没有识别结果可以保存！', '错误');
        return;
    end

    % 选择保存路径
    [fileName, filePath] = uiputfile('*.txt', '保存转写结果');
    if isequal(fileName, 0) || isequal(filePath, 0)
        return; % 用户取消
    end

    % 保存文件
    fullFilePath = fullfile(filePath, fileName);
    fileID = fopen(fullFilePath, 'w');
    fprintf(fileID, '%s', app.recognizedText);
    fclose(fileID);

    % 提示保存成功
    uialert(app.UIFigure, '识别结果已保存！', '成功');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '语音信号处理系统';
            app.before_wave = uiaxes(app.UIFigure);
            title(app.before_wave, '原信号波形')
            xlabel(app.before_wave, 't/s')
            ylabel(app.before_wave, '幅度')
            zlabel(app.before_wave, 'Z')
            app.before_wave.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.before_Spectrum = uiaxes(app.UIFigure);
            title(app.before_Spectrum, '原信号频谱')
            xlabel(app.before_Spectrum, 'ω/\pi')
            ylabel(app.before_Spectrum, '幅度')
            zlabel(app.before_Spectrum, 'Z')
            app.after_wave = uiaxes(app.UIFigure);
            title(app.after_wave, '处理后信号波形')
            xlabel(app.after_wave, 't/s')
            ylabel(app.after_wave, '幅度')
            zlabel(app.after_wave, 'Z')
            app.after_Spectrum = uiaxes(app.UIFigure);
            title(app.after_Spectrum, '处理后信号频谱')
            xlabel(app.after_Spectrum, 'ω/\pi')
            ylabel(app.after_Spectrum, '幅度')
            zlabel(app.after_Spectrum, 'Z')
            app.readin_music = uibutton(app.UIFigure, 'push');
            app.readin_music.ButtonPushedFcn = createCallbackFcn(app, @readin_musicButtonPushed, true);
            app.readin_music.Text = '导入音频';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '语音信号处理系统';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '音频信息';
            app.music_information = uitextarea(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '基本处理';
            app.Label_9 = uilabel(app.Tab);
            app.Label_9.Text = '变调';
            app.change_voice = uislider(app.Tab);
            app.change_voice.Limits = [-10 10];
            app.change_voice.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.change_voice.ValueChangedFcn = createCallbackFcn(app, @change_voiceValueChanged, true);
            app.change_voice.MinorTicks = [-9.5 -8.5 -7.5 -6.5 -5.5 -4.5 -3.5 -2.5 -1.5 -0.5 0.5 1.5 2.5 3.5 4.5 5.5 6.5 7.5 8.5 9.5];
            app.Label_10 = uilabel(app.Tab);
            app.Label_10.Text = '回声程度';
            app.voice_echo = uislider(app.Tab);
            app.voice_echo.ValueChangedFcn = createCallbackFcn(app, @voice_echoValueChanged, true);
            app.Label_13 = uilabel(app.Tab);
            app.Label_13.Text = '减采样频率';
            app.resample_num = uieditfield(app.Tab, 'numeric');
            app.resample_num.ValueChangedFcn = createCallbackFcn(app, @resample_numValueChanged, true);
            app.play_music_2 = uibutton(app.Tab, 'push');
            app.play_music_2.ButtonPushedFcn = createCallbackFcn(app, @play_music_2ButtonPushed, true);
            app.play_music_2.Text = '播放音频';
            app.resampleok = uibutton(app.Tab, 'push');
            app.resampleok.ButtonPushedFcn = createCallbackFcn(app, @resampleokButtonPushed, true);
            app.resampleok.Text = '确定减抽样';
            app.AM = uipanel(app.Tab);
            app.AM.Title = 'AM调制与解调';
            app.Switch_2 = uiswitch(app.AM, 'toggle');
            app.Switch_2.Items = {'解调', '调制'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = '调制';
            app.Label_16 = uilabel(app.AM);
            app.Label_16.Text = '载波频率';
            app.EditField_2 = uieditfield(app.AM, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Button_2 = uibutton(app.AM, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '调制';
            app.ButtonGroup = uibuttongroup(app.AM);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '选择滤波器解调';
            app.buttordd = uiradiobutton(app.ButtonGroup);
            app.buttordd.Text = '巴特沃斯滤波';
            app.buttordd.Value = true;
            app.rectwinn = uiradiobutton(app.ButtonGroup);
            app.rectwinn.Text = '矩形窗';
            app.blackmann = uiradiobutton(app.ButtonGroup);
            app.blackmann.Text = '布莱克曼窗';
            app.filterr = uibutton(app.ButtonGroup, 'push');
            app.filterr.ButtonPushedFcn = createCallbackFcn(app, @filterrButtonPushed, true);
            app.filterr.Text = '滤波';
            app.plus_noise = uipanel(app.Tab);
            app.plus_noise.Title = '加噪去噪';
            app.Button_7 = uibutton(app.plus_noise, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '加三余弦噪声';
            app.Button_8 = uibutton(app.plus_noise, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '滤三余弦噪声';
            app.Button_9 = uibutton(app.plus_noise, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '加随机白噪声';
            app.Button_10 = uibutton(app.plus_noise, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '滤随机白噪声';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '幅度及相位交叉';
            app.readin_music_2 = uibutton(app.Panel_2, 'push');
            app.readin_music_2.ButtonPushedFcn = createCallbackFcn(app, @readin_music_2ButtonPushed, true);
            app.readin_music_2.Text = '导入音频2';
            app.Button_11 = uibutton(app.Panel_2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '音频1幅度与音频2相位交叉';
            app.Button_12 = uibutton(app.Panel_2, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '音频2幅度与音频1相位交叉';
            app.Label_15 = uilabel(app.Tab);
            app.Label_15.Text = '音量大小';
            app.voice_amplitude = uislider(app.Tab);
            app.voice_amplitude.ValueChangedFcn = createCallbackFcn(app, @voice_amplitudeValueChanged, true);
            app.voice_amplitude.Value = 10;
            app.hunyin = uibutton(app.Tab, 'push');
            app.hunyin.ButtonPushedFcn = createCallbackFcn(app, @hunyinButtonPushed, true);
            app.hunyin.Text = '混音';
            app.stop = uibutton(app.Tab, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stop.Text = '暂停';
            app.Label_17 = uilabel(app.Tab);
            app.Label_17.Text = '变速';
            app.change_velocity = uislider(app.Tab);
            app.change_velocity.Limits = [-10 10];
            app.change_velocity.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.change_velocity.ValueChangedFcn = createCallbackFcn(app, @change_velocityValueChanged, true);
            app.change_velocity.MinorTicks = [-9.5 -8.5 -7.5 -6.5 -5.5 -4.5 -3.5 -2.5 -1.5 -0.5 0.5 1.5 2.5 3.5 4.5 5.5 6.5 7.5 8.5 9.5];
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '滤波器设计及处理';
            app.Filter_amplitude = uiaxes(app.Tab_2);
            title(app.Filter_amplitude, '滤波器幅频特性')
            xlabel(app.Filter_amplitude, 'f/Hz')
            ylabel(app.Filter_amplitude, '幅值(dB)')
            zlabel(app.Filter_amplitude, 'Z')
            app.Filter_phase = uiaxes(app.Tab_2);
            title(app.Filter_phase, '滤波器相频特性')
            xlabel(app.Filter_phase, 'f/Hz')
            ylabel(app.Filter_phase, '相位(\pi)')
            zlabel(app.Filter_phase, 'Z')
            app.Filter_design = uipanel(app.Tab_2);
            app.Filter_design.Title = '设计滤波器';
            app.Switch = uiswitch(app.Filter_design, 'toggle');
            app.Switch.Items = {'IIR', 'FIR'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'IIR';
            app.IIRPanel = uipanel(app.Filter_design);
            app.IIRPanel.Title = 'IIR';
            app.Label_2 = uilabel(app.IIRPanel);
            app.Label_2.Text = '滤波器型号';
            app.IIR_filterchoose = uidropdown(app.IIRPanel);
            app.IIR_filterchoose.Items = {'1.巴特沃斯滤波器', '2.切比雪夫Ⅰ型', '3.切比雪夫Ⅱ型', '4.椭圆滤波器'};
            app.IIR_filterchoose.ValueChangedFcn = createCallbackFcn(app, @IIR_filterchooseValueChanged, true);
            app.IIR_filterchoose.Value = '1.巴特沃斯滤波器';
            app.DropDown_3Label = uilabel(app.IIRPanel);
            app.DropDown_3Label.Text = '滤波器性质';
            app.IIR_choose = uidropdown(app.IIRPanel);
            app.IIR_choose.Items = {'1.低通', '2.高通', '3.带通', '4.带阻'};
            app.IIR_choose.ValueChangedFcn = createCallbackFcn(app, @IIR_chooseValueChanged, true);
            app.IIR_choose.Value = '1.低通';
            app.Label_7 = uilabel(app.IIRPanel);
            app.Label_7.Text = '设计方法';
            app.IIR_method = uidropdown(app.IIRPanel);
            app.IIR_method.Items = {'1.脉冲响应不变法', '2.双线性变换法'};
            app.IIR_method.ValueChangedFcn = createCallbackFcn(app, @IIR_methodValueChanged, true);
            app.IIR_method.Value = '1.脉冲响应不变法';
            app.IIRLabel = uilabel(app.IIRPanel);
            app.IIRLabel.Text = 'IIR滤波器参数';
            app.FsLabel = uilabel(app.IIRPanel);
            app.FsLabel.Text = '阻带最小衰减(Rs/dB)';
            app.IIR_Rs = uieditfield(app.IIRPanel, 'numeric');
            app.IIR_Rs.ValueChangedFcn = createCallbackFcn(app, @IIR_RsValueChanged, true);
            app.RpdBEditField_2Label = uilabel(app.IIRPanel);
            app.RpdBEditField_2Label.Text = '通带最大衰减(Rp/dB)';
            app.IIR_Rp = uieditfield(app.IIRPanel, 'numeric');
            app.IIR_Rp.ValueChangedFcn = createCallbackFcn(app, @IIR_RpValueChanged, true);
            app.FpHzEditFieldLabel = uilabel(app.IIRPanel);
            app.FpHzEditFieldLabel.Text = '通带截止频率(Fp/Hz)';
            app.IIR_Fp = uieditfield(app.IIRPanel, 'text');
            app.IIR_Fp.ValueChangedFcn = createCallbackFcn(app, @IIR_FpValueChanged, true);
            app.IIR_Fp.Value = '0';
            app.FpHzEditField_2Label_2 = uilabel(app.IIRPanel);
            app.FpHzEditField_2Label_2.Text = '阻带截止频率(Fs/Hz)';
            app.IIR_Fs = uieditfield(app.IIRPanel, 'text');
            app.IIR_Fs.ValueChangedFcn = createCallbackFcn(app, @IIR_FsValueChanged, true);
            app.IIR_Fs.Value = '0';
            app.FIRPanel = uipanel(app.Filter_design);
            app.FIRPanel.Title = 'FIR';
            app.DropDown_5Label = uilabel(app.FIRPanel);
            app.DropDown_5Label.Text = '设计方法';
            app.FIR_method = uidropdown(app.FIRPanel);
            app.FIR_method.Items = {'1.窗函数设计法', '2.等波纹最佳逼近法'};
            app.FIR_method.ValueChangedFcn = createCallbackFcn(app, @FIR_methodValueChanged, true);
            app.FIR_method.Value = '1.窗函数设计法';
            app.Label_12 = uilabel(app.FIRPanel);
            app.Label_12.Text = '窗类型';
            app.win_choose = uidropdown(app.FIRPanel);
            app.win_choose.Items = {'1.汉宁窗', '2.汉明窗', '3.布莱克曼窗', '4.矩形窗', '5.三角型窗', '6.凯塞窗'};
            app.win_choose.ValueChangedFcn = createCallbackFcn(app, @win_chooseValueChanged, true);
            app.win_choose.Value = '1.汉宁窗';
            app.DropDown_7Label = uilabel(app.FIRPanel);
            app.DropDown_7Label.Text = '滤波器性质';
            app.FIR_choose = uidropdown(app.FIRPanel);
            app.FIR_choose.Items = {'1.低通', '2.高通', '3.带通', '4.带阻'};
            app.FIR_choose.ValueChangedFcn = createCallbackFcn(app, @FIR_chooseValueChanged, true);
            app.FIR_choose.Value = '1.低通';
            app.FIRLabel = uilabel(app.FIRPanel);
            app.FIRLabel.Text = 'FIR滤波器参数';
            app.RsdBEditFieldLabel = uilabel(app.FIRPanel);
            app.RsdBEditFieldLabel.Text = '阻带最小衰减(Rs/dB)';
            app.FIR_Rs = uieditfield(app.FIRPanel, 'numeric');
            app.FIR_Rs.ValueChangedFcn = createCallbackFcn(app, @FIR_RsValueChanged, true);
            app.RpdBEditFieldLabel = uilabel(app.FIRPanel);
            app.RpdBEditFieldLabel.Text = '通带最大衰减(Rp/dB)';
            app.FIR_Rp = uieditfield(app.FIRPanel, 'numeric');
            app.FIR_Rp.ValueChangedFcn = createCallbackFcn(app, @FIR_RpValueChanged, true);
            app.FpHzEditFieldLabel_2 = uilabel(app.FIRPanel);
            app.FpHzEditFieldLabel_2.Text = '通带截止频率(Fp/Hz)';
            app.FIR_Fp = uieditfield(app.FIRPanel, 'text');
            app.FIR_Fp.ValueChangedFcn = createCallbackFcn(app, @FIR_FpValueChanged, true);
            app.FIR_Fp.Value = '0';
            app.FsHzEditFieldLabel = uilabel(app.FIRPanel);
            app.FsHzEditFieldLabel.Text = '阻带截止频率(Fs/Hz)';
            app.FIR_Fs = uieditfield(app.FIRPanel, 'text');
            app.FIR_Fs.ValueChangedFcn = createCallbackFcn(app, @FIR_FsValueChanged, true);
            app.FIR_Fs.Value = '0';
            app.get_filter = uibutton(app.Filter_design, 'push');
            app.get_filter.ButtonPushedFcn = createCallbackFcn(app, @get_filterButtonPushed, true);
            app.get_filter.Text = '创建';
            app.ifok = uibutton(app.Tab_2, 'push');
            app.ifok.ButtonPushedFcn = createCallbackFcn(app, @ifokButtonPushed, true);
            app.ifok.Text = '确定参数';
            app.play_music = uibutton(app.Tab_2, 'push');
            app.play_music.ButtonPushedFcn = createCallbackFcn(app, @play_musicButtonPushed, true);
            app.play_music.Text = '播放音频';
            app.stop_2 = uibutton(app.Tab_2, 'push');
            app.stop_2.ButtonPushedFcn = createCallbackFcn(app, @stop_2ButtonPushed, true);
            app.stop_2.Text = '暂停';
            app.voice_process = uitab(app.TabGroup);
            app.voice_process.Title = '人声处理';
            app.TextAreaLabel = uilabel(app.voice_process);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextBox = uitextarea(app.voice_process);
            app.TextBox.Tag = 'TexBox';
            app.Button_13 = uibutton(app.voice_process, 'push');
            app.Button_13.Text = '提取人声';
            app.SaveTextButton = uibutton(app.voice_process, 'push');
            app.SaveTextButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTextButtonPushed, true);
            app.SaveTextButton.Tag = 'SaveTextButton';
            app.SaveTextButton.Text = '下载文字文本';
            app.recognize_word = uibutton(app.voice_process, 'push');
            app.recognize_word.ButtonPushedFcn = createCallbackFcn(app, @recognize_wordButtonPushed, true);
            app.recognize_word.Tag = 'recognize_word';
            app.recognize_word.Text = '开始识别文字';
            app.Button_16 = uibutton(app.voice_process, 'push');
            app.Button_16.Text = '播放人声';
            app.note_Label = uilabel(app.voice_process);
            app.note_Label.Text = '识别文字预览';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sginal_processer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Mystartup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end