classdef lls_brid_recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        recognitionLabel  matlab.ui.control.Label
        recognitionImage  matlab.ui.control.Image
        Image2            matlab.ui.control.Image
        recognizeButton   matlab.ui.control.Button
        uploadButton      matlab.ui.control.Button
        playButton        matlab.ui.control.Button
        Image             matlab.ui.control.Image
        freqDomainAxes    matlab.ui.control.UIAxes
        timeDomainAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        audioData    % 音频数据
        sampleRate   % 采样率
        Property3 % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: uploadButton
        function uploadButtonPushed(app, event)

    % 打开文件对话框选择音频
    [file, path] = uigetfile({'*.wav;*.mp3', '音频文件 (*.wav, *.mp3)'}, '选择音频文件');
    if isequal(file, 0)  % 用户取消
        uialert(app.UIFigure, '未选择文件！', '提示');
        return;
    end

    % 加载音频
    fullFilePath = fullfile(path, file);
    try
        [app.audioData, app.sampleRate] = audioread(fullFilePath);
        uialert(app.UIFigure, ['成功加载音频文件: ', file], '成功');
        
        % 绘制时域图
        plot(app.timeDomainAxes, (1:length(app.audioData)) / app.sampleRate, app.audioData);
        title(app.timeDomainAxes, '时域图');
        xlabel(app.timeDomainAxes, '时间 (秒)');
        ylabel(app.timeDomainAxes, '幅值');
        
        fftData = abs(fft(app.audioData)); % 计算 FFT
        N = length(app.audioData);         % 音频数据的长度
        freq = (0:N-1) * app.sampleRate / N; % 计算频率向量

        % 获取单边频谱（去除冗余部分）
        fftDataSingleSided = fftData(1:floor(N/2)+1);  % 保留第一个一半
        fftDataSingleSided(2:end-1) = 2 * fftDataSingleSided(2:end-1); % 乘2，保持能量一致

        % 转换为对数单位（dB）
        fftDataSingleSided_dB = 20 * log10(fftDataSingleSided); 
        fftDataSingleSided_dB = max(fftDataSingleSided_dB, -100); % 避免显示过低的值

        % 计算频率向量
        freqSingleSided = freq(1:floor(N/2)+1);

        % 绘制频域图
        plot(app.freqDomainAxes, freqSingleSided, fftDataSingleSided_dB); % 黑色线条
        title(app.freqDomainAxes, '单边频谱 (dB)');
        xlabel(app.freqDomainAxes, '频率 (Hz)');
        ylabel(app.freqDomainAxes, '幅值 (dB)');
    catch
        uialert(app.UIFigure, '加载音频文件失败，请检查格式！', '错误');
    end
        end

        % Callback function
        function uploadButton_2Pushed(app, event)
            
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)

    if isempty(app.audioData)
        uialert(app.UIFigure, '请先上传音频文件！', '错误');
        return;
    end
    sound(app.audioData, app.sampleRate); % 播放音频
    uialert(app.UIFigure, '正在播放音频...', '提示');
        end

        % Button pushed function: recognizeButton
        function recognizeButtonPushed(app, event)

    % 检查是否已经加载音频数据
    if isempty(app.audioData)
        uialert(app.UIFigure, '请先上传音频文件！', '错误');
        return;
    end

    % 调用音频识别算法
    result = myAudioRecognitionAlgorithm(app.audioData, app.sampleRate);

    % 更新识别标签
    app.recognitionLabel.Text = [result];
    
    % 获取当前文件夹路径
    currentFolder = pwd;

    % 根据识别结果更新图像
    switch result
        case '云雀'
            % 显示云雀图片
            app.recognitionImage.ImageSource = fullfile(currentFolder, '云雀.jpg');
        case '斑鸠'
            % 显示斑鸠图片
            app.recognitionImage.ImageSource = fullfile(currentFolder, '斑鸠.jpg');
        case '喜鹊'
            % 显示喜鹊图片
            app.recognitionImage.ImageSource = fullfile(currentFolder, '喜鹊.jpg');
        case '大雁'
            % 显示大雁图片
            app.recognitionImage.ImageSource = fullfile(currentFolder, '大雁.jpg');
        case '布谷鸟'
            % 显示布谷鸟图片
            app.recognitionImage.ImageSource = fullfile(currentFolder, '布谷鸟.png');
        otherwise
            % 如果无法识别，显示默认图片或消息
            app.recognitionImage.ImageSource = fullfile(currentFolder, 'default_image.png');
    end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.timeDomainAxes = uiaxes(app.UIFigure);
            title(app.timeDomainAxes, '时域信号波形')
            xlabel(app.timeDomainAxes, 't/s')
            zlabel(app.timeDomainAxes, 'Z')
            app.timeDomainAxes.Tag = 'timeDomainAxes';
            app.freqDomainAxes = uiaxes(app.UIFigure);
            title(app.freqDomainAxes, '频频信号波形')
            xlabel(app.freqDomainAxes, 'w/\pi')
            zlabel(app.freqDomainAxes, 'Z')
            app.freqDomainAxes.Tag = 'freqDomainAxes';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Photo', 'bird-8149449.jpg');
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Tag = 'playButton';
            app.playButton.Text = '播放音频';
            app.uploadButton = uibutton(app.UIFigure, 'push');
            app.uploadButton.ButtonPushedFcn = createCallbackFcn(app, @uploadButtonPushed, true);
            app.uploadButton.Tag = 'uploadButton';
            app.uploadButton.Text = '点击上传音频';
            app.recognizeButton = uibutton(app.UIFigure, 'push');
            app.recognizeButton.ButtonPushedFcn = createCallbackFcn(app, @recognizeButtonPushed, true);
            app.recognizeButton.Tag = 'recognizeButton';
            app.recognizeButton.Text = '音频识别';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Photo', 'WechatIMG13.jpg');
            app.recognitionImage = uiimage(app.UIFigure);
            app.recognitionImage.Tag = 'recognitionImage';
            app.recognitionImage.ImageSource = fullfile(pathToMLAPP, 'Photo', 'default_image.png');
            app.recognitionLabel = uilabel(app.UIFigure);
            app.recognitionLabel.Tag = 'recognitionLabel';
            app.recognitionLabel.Text = {'？？？？'; '我是谁'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '声音识别系统';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lls_brid_recognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end